classdef HoloApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModePanel                       matlab.ui.container.Panel
        BackLayout                      matlab.ui.container.GridLayout
        SideLayout                      matlab.ui.container.GridLayout
        LogoNavLayout                   matlab.ui.container.GridLayout
        NavigationLayout                matlab.ui.container.GridLayout
        GPUProcessingCheckBox           matlab.ui.control.CheckBox
        ShiftButton                     matlab.ui.control.StateButton
        LoadDataButton                  matlab.ui.control.StateButton
        ExportResultsButton             matlab.ui.control.StateButton
        ReconstructionButton            matlab.ui.control.StateButton
        FocusButton                     matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
        MainLayout                      matlab.ui.container.GridLayout
        NavigationTab                   matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        LoadDataLayout                  matlab.ui.container.GridLayout
        LoadDataDisplayLayout           matlab.ui.container.GridLayout
        LoadDataGraphSettingsLayout     matlab.ui.container.GridLayout
        LoadDataRangeEndEditField       matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        LoadDataRangeStartEditField     matlab.ui.control.NumericEditField
        ValuerangeEditField_2Label      matlab.ui.control.Label
        LoadDataDisplayButtonsLayout    matlab.ui.container.GridLayout
        LoadDataNextButton              matlab.ui.control.Button
        LoadDataPreviousButton          matlab.ui.control.Button
        LoadDataUIAxes                  matlab.ui.control.UIAxes
        LoadDataControlLayout           matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthmEditFieldLabel       matlab.ui.control.Label
        PixelSizeEditField              matlab.ui.control.NumericEditField
        PixelSizemLabel                 matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightmLabel                    matlab.ui.control.Label
        ModeTabGroup                    matlab.ui.container.TabGroup
        MultiwavelenghtTab              matlab.ui.container.Tab
        MultiwavelengthLayout           matlab.ui.container.GridLayout
        PhotoWavelengthTable            matlab.ui.control.Table
        MultiheightTab                  matlab.ui.container.Tab
        MultiheightLayout               matlab.ui.container.GridLayout
        PhotoHeightTable                matlab.ui.control.Table
        ModeLabel                       matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        DefaultvaluesLabel              matlab.ui.control.Label
        LoadHologramsButton             matlab.ui.control.Button
        LoadDataFilesPanel              matlab.ui.container.Panel
        LoadDataFileListBoxLayout       matlab.ui.container.GridLayout
        LoadDataFilesListBox            matlab.ui.control.ListBox
        HologramsLabel                  matlab.ui.control.Label
        FocusTab                        matlab.ui.container.Tab
        FocusLayout                     matlab.ui.container.GridLayout
        FocusDisplayLayout              matlab.ui.container.GridLayout
        FocusGraphSettingsLayout        matlab.ui.container.GridLayout
        FocusRangeEndEditField          matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        FocusRangeStartEditField        matlab.ui.control.NumericEditField
        ValuerangeEditFieldLabel        matlab.ui.control.Label
        FocusDisplayButtonsLayout       matlab.ui.container.GridLayout
        FocusPhaseButton                matlab.ui.control.StateButton
        FocusAmplitudeButton            matlab.ui.control.StateButton
        FocusNextButton                 matlab.ui.control.Button
        FocusPreviousButton             matlab.ui.control.Button
        FocusUIAxes                     matlab.ui.control.UIAxes
        FocusControlLayout              matlab.ui.container.GridLayout
        FocusingPanel                   matlab.ui.container.Panel
        AdjustFocusLayout               matlab.ui.container.GridLayout
        InspectROICheckboxLayout        matlab.ui.container.GridLayout
        InspectROICheckBox              matlab.ui.control.CheckBox
        AdjustFocusLabel                matlab.ui.control.Label
        SweepAutoFocusLayout            matlab.ui.container.GridLayout
        AutofocusButton                 matlab.ui.control.Button
        SweepStepEditField              matlab.ui.control.NumericEditField
        SweepStopLabel                  matlab.ui.control.Label
        SweepRangeEditField             matlab.ui.control.NumericEditField
        SweepStepLabel                  matlab.ui.control.Label
        SweepStartEditField             matlab.ui.control.NumericEditField
        StartmLabel                     matlab.ui.control.Label
        DarkfocusLabel                  matlab.ui.control.Label
        CurrentHeightControlLayout      matlab.ui.container.GridLayout
        CurrentHeightSubtractButton     matlab.ui.control.StateButton
        CurrentHeightAddButton          matlab.ui.control.StateButton
        CurrentHeightEditField          matlab.ui.control.NumericEditField
        CurrentHeightmLabel             matlab.ui.control.Label
        StepControlLayout               matlab.ui.container.GridLayout
        StepAddButton                   matlab.ui.control.Button
        StepSubtractButton              matlab.ui.control.Button
        StepEditField                   matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        FocusFilesPanel                 matlab.ui.container.Panel
        FocusFileListBoxLayout          matlab.ui.container.GridLayout
        FocusFilesListBox               matlab.ui.control.ListBox
        HologramsLabel_2                matlab.ui.control.Label
        ShiftTab                        matlab.ui.container.Tab
        ShiftLayout                     matlab.ui.container.GridLayout
        ShiftControlLayout              matlab.ui.container.GridLayout
        ShiftHologramTypeDropDown       matlab.ui.control.DropDown
        GridLayout3_2                   matlab.ui.container.GridLayout
        XYTable                         matlab.ui.control.Table
        ManualcorrectButton             matlab.ui.control.Button
        HeightCorrectionTable           matlab.ui.control.Table
        AutocorrectButton               matlab.ui.control.Button
        CorrectShiftLabel               matlab.ui.control.Label
        ShiftFilesPanel                 matlab.ui.container.Panel
        ShiftFileListBoxLayout          matlab.ui.container.GridLayout
        ShiftFilesListBox               matlab.ui.control.ListBox
        FilesListBox_3Label_2           matlab.ui.control.Label
        ShiftDisplayLayout              matlab.ui.container.GridLayout
        FocusGrahpSettingsLayout_2      matlab.ui.container.GridLayout
        ShiftRangeEndEditField          matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        ShiftRangeStartEditField        matlab.ui.control.NumericEditField
        ValuerangeEditFieldLabel_2      matlab.ui.control.Label
        ShiftDisplayButtonsLayout       matlab.ui.container.GridLayout
        ShiftPhaseButton                matlab.ui.control.StateButton
        ShiftAmplitudeButton            matlab.ui.control.StateButton
        ShiftNextButton                 matlab.ui.control.Button
        ShiftPreviousButton             matlab.ui.control.Button
        ShiftUIAxes                     matlab.ui.control.UIAxes
        ReconstructionTab               matlab.ui.container.Tab
        ReconstructionLayout            matlab.ui.container.GridLayout
        ReconstructionControlLayout     matlab.ui.container.GridLayout
        GridLayout3_3                   matlab.ui.container.GridLayout
        ReconstructionParametersLayout  matlab.ui.container.GridLayout
        IterationNumberEditField        matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel   matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        StartReconstruction             matlab.ui.control.Button
        ReconstructioncontrolLabel      matlab.ui.control.Label
        ReconstructionHologramsPanel    matlab.ui.container.Panel
        ReconstructionHologramsListBoxLayout  matlab.ui.container.GridLayout
        ReconstructionHologramsListBox  matlab.ui.control.ListBox
        FilesListBox_3Label_3           matlab.ui.control.Label
        ReconstructionDisplayLayout     matlab.ui.container.GridLayout
        FocusGrahpSettingsLayout_3      matlab.ui.container.GridLayout
        ReconstructionRangeEndEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        ReconstructionRangeStartEditField  matlab.ui.control.NumericEditField
        ValuerangeEditFieldLabel_3      matlab.ui.control.Label
        ReconstructionDisplayButtonsLayout  matlab.ui.container.GridLayout
        ReconstructionPhaseButton       matlab.ui.control.StateButton
        ReconstructionAmplitudeButton   matlab.ui.control.StateButton
        ReconstructionNextButton        matlab.ui.control.Button
        ReconstructionPreviousButton    matlab.ui.control.Button
        ReconstructionUIAxes            matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        ResultsLayout                   matlab.ui.container.GridLayout
        SaveSelectorLayout              matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        MoveAllLeft                     matlab.ui.control.Button
        MoveSingleLeft                  matlab.ui.control.Button
        MoveSingleRight                 matlab.ui.control.Button
        MoveAllRight                    matlab.ui.control.Button
        ExportedHologramsListBox        matlab.ui.control.ListBox
        ExportedHologramsListBoxLabel   matlab.ui.control.Label
        AvailableHologramsListBox       matlab.ui.control.ListBox
        AvailableHologramsListBoxLabel  matlab.ui.control.Label
        SaveSettingsLayout              matlab.ui.container.GridLayout
        ExportSettingsLayout            matlab.ui.container.GridLayout
        DropDownLayout                  matlab.ui.container.GridLayout
        FormatDropDown                  matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        DropDownLayout_2                matlab.ui.container.GridLayout
        ExportModeDropDown              matlab.ui.control.DropDown
        ExportModeDropDownLabel         matlab.ui.control.Label
        ExporthologramsButton           matlab.ui.control.Button
        ExportsettingsLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentHologramIndex (1,1) uint16 = 1  % index of selected hologram
        UsedHeights (1,:) double = []          % recently used hologram distances
        IsShiftManualCorrectionOn  = false     % manual shift correction flag
        FocusROIRectanglePosition = 0

        %Derrived holograms
        HologramsPostPropagation(:,:,:)                          = []
        CorrectedReference      (:,:,:)                          = []
        CorrectedHolograms      (:,:,:)                          = []
        FinalReconstructions    (:,:,:)                          = []
        
        %Derrived data
        HologramsPostPropagationNames cell                       = {}
        CorrectedReferenceNames cell                             = {}
        CorrectedHologramsNames cell                             = {}
        FinalReconstructionsNames cell                           = {}
        CorrectedZCoordinates   (1,:) double {mustBeNonnegative} = []
        
        %User input
        ReconstructionSigmas    (1,:) double {mustBeNonnegative} = []
        ReconstructionIterationNumber (1,:)                      = []
        DeterminedHeights       (1,:) double {mustBeNonnegative} = []
        AmplitudeDisplayRange (1,2) double                       = [0, 255]
        PhaseDisplayRange (1,2) double                           = [-3.14, 3.14]
        ManualCorrectionXY cell                                  = {}
        GPUProcessingStatus                                      = 0
        QCIModel QCI_Model                                       = QCI_Model()       % Model used for hologram transforms
    end
    
    properties (Access = public)
        Files

        %Derrived holograms
        HologramsRaw            (:,:,:)                          = []

        %Derrived data
        HologramsRawNames cell                                   = {}
    end
    
    methods (Access = private)
        %% Model
        function LoadHologramsToQCIModel(app)
            if(~isempty(app.HologramsRaw))
                % Getting default lambdas, heights, and camera pixel sizes
                imageNumber = length(app.HologramsRawNames);
                wavelengths = ones(1,imageNumber)*app.WavelengthEditField.Value;
                heights = ones(1,imageNumber)*app.HeightEditField.Value;
                pixelSize = app.PixelSizeEditField.Value;
                app.UsedHeights = zeros(1,imageNumber);

                % Initializing the model
                
                if(app.GPUProcessingStatus)
                     app.QCIModel = QCI_Model_GPU(app.HologramsRawNames, app.HologramsRaw, wavelengths, heights, pixelSize);
                else
                    app.QCIModel = QCI_Model(app.HologramsRawNames, app.HologramsRaw, wavelengths, heights, pixelSize);
                end

                app.HologramsRaw = app.QCIModel.getHolograms(0);
                app.HologramsPostPropagation = app.HologramsRaw;
            end
        end

        %% View
        function HandleHologramChange(app, newFileIndex)
            if(isempty(fieldnames(app.Files)))
                return
            end

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                hologramCount = size(app.HologramsRaw,3);
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                hologramCount = size(app.HologramsPostPropagation,3);
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                hologramCount = size(app.HologramsRaw,3);
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                hologramCount = size(app.FinalReconstructions, 3);
            end

            if(hologramCount == 1)
                app.CurrentHologramIndex = 1;
            elseif (newFileIndex < 1)
                app.CurrentHologramIndex = hologramCount;
            elseif(newFileIndex == hologramCount)
                app.CurrentHologramIndex = hologramCount;
            else
                app.CurrentHologramIndex = mod(newFileIndex, hologramCount);
            end

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                
                app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;

                app.LoadDataRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
                app.LoadDataRangeEndEditField.Value = app.AmplitudeDisplayRange(2);

                image = app.HologramsRaw(:,:, app.CurrentHologramIndex);
                imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.LoadDataUIAxes);

                if(app.LoadDataUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.LoadDataUIAxes.YLim(2) > size(app.HologramsRaw,1))
                    app.LoadDataUIAxes.XLim(2) = size(app.HologramsRaw,2);
                    app.LoadDataUIAxes.YLim(2) = size(app.HologramsRaw,1);
                end
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                app.FocusFilesListBox.Value = app.CurrentHologramIndex;
                
                all=0;
                heights = app.QCIModel.getZCoordinates(all);
                app.CurrentHeightEditField.Value=heights(app.CurrentHologramIndex);

                app.DisplayFocus();
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                app.LoadShiftListBox();
                app.ShiftFilesListBox.Value = app.CurrentHologramIndex;
                

                app.DisplayShift(app.IsShiftManualCorrectionOn);
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                app.LoadReconstructionListBox();

                if(~isempty(app.ReconstructionHologramsListBox.Value))
                    app.ReconstructionHologramsListBox.Value = app.CurrentHologramIndex;
                end

                app.DisplayReconstruction();
            end
        end
        
        function HandleModeChange(app, newModeValue)
            switch newModeValue
                case 'Single hologram' %SingleHologram
                    app.ModeTabGroup.Visible = "off";
                case 'Multi-wavelenght' %Multi-wavelength
                    app.ModeTabGroup.SelectedTab = app.MultiwavelenghtTab;
                    app.ModeTabGroup.Visible = "on";
                case 'Multi-height' %Multi-height
                    app.ModeTabGroup.SelectedTab = app.MultiheightTab;
                    app.ModeTabGroup.Visible = "on";
                otherwise
                    return
            end
        end
        
        function LoadModeTableData(app)
            all = 0;
            heights = app.QCIModel.getZCoordinates(all);
            wavelengths = app.QCIModel.getWavelengths(all);
            names = app.QCIModel.getNames(all);
            app.PhotoHeightTable.Data =[names.' heights.'];
            app.PhotoWavelengthTable.Data = [names.' wavelengths.'];
        end

        function LoadXYTableData(app)
            pointsStruct = app.ManualCorrectionXY{app.CurrentHologramIndex};

            for pointIndex = 1:3
                x(pointIndex) = pointsStruct{pointIndex}(1);
                y(pointIndex) = pointsStruct{pointIndex}(2);
            end

            app.XYTable.Data = [x.' y.'];
        end

        function DisplayFocus(app)    
            all = 0;
            heights = app.QCIModel.getZCoordinates(all);
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;

            if(app.UsedHeights(app.CurrentHologramIndex) == heights(app.CurrentHologramIndex))
                if(heights(app.CurrentHologramIndex) == 0)
                    image = app.HologramsRaw(:,:,app.CurrentHologramIndex);
                else
                    image = app.HologramsPostPropagation(:,:,app.CurrentHologramIndex);
                end
            else
                app.QCIModel.setZCoordinates(heights);
                app.UsedHeights(app.CurrentHologramIndex) = heights(app.CurrentHologramIndex);
                app.HologramsPostPropagation(:,:,app.CurrentHologramIndex) = app.QCIModel.propagate(app.CurrentHologramIndex);
                image = app.HologramsPostPropagation(:,:,app.CurrentHologramIndex);
            end

            if(app.FocusAmplitudeButton.Value == 1)
                app.FocusRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
                app.FocusRangeEndEditField.Value = app.AmplitudeDisplayRange(2);

                image = abs(image);
                imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.FocusUIAxes);
            else
                app.FocusRangeStartEditField.Value = app.PhaseDisplayRange(1);
                app.FocusRangeEndEditField.Value = app.PhaseDisplayRange(2);

                image = angle(image);
                imshow(image, app.PhaseDisplayRange, "Parent", app.FocusUIAxes);
            end
            
            if(app.FocusUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.FocusUIAxes.YLim(2) > size(app.HologramsRaw,1))
                app.FocusUIAxes.XLim(2) = size(app.HologramsRaw,2);
                app.FocusUIAxes.YLim(2) = size(app.HologramsRaw,1);
            end

            if(app.InspectROICheckBox.Value)
                if(app.FocusROIRectanglePosition ~= 0)
                    rectangle = drawrectangle(app.FocusUIAxes, 'Color', 'b', "Position", app.FocusROIRectanglePosition);
                else
                    rectangle = drawrectangle(app.FocusUIAxes, 'Color', 'b');
                    rectangle.Position = round(rectangle.Position);
                    app.FocusROIRectanglePosition = rectangle.Position;
                end

                addlistener(rectangle, 'ROIMoved', @app.FocusROIUpdateRectangleListener);
            end
        end

        function DisplayShift(app, isManualShiftCorrectionOn)
           if(nargin<2)
               isManualShiftCorrectionOn = false;
           end

           if(isManualShiftCorrectionOn)
               app.DisplayShiftManualCorrection();
           else
               app.DisplayShiftDefault();
           end

            if(app.ShiftUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.ShiftUIAxes.YLim(2) > size(app.HologramsRaw,1))
                app.ShiftUIAxes.XLim(2) = size(app.HologramsRaw,2);
                app.ShiftUIAxes.YLim(2) = size(app.HologramsRaw,1);
            end
        end
        
        function DisplayShiftManualCorrection(app)
            if(isempty(app.ManualCorrectionXY))
                referenceImage = app.HologramsPostPropagation(:,:, end);
    
                app.DisplayShiftImage(referenceImage);
                title(app.ShiftUIAxes,"Reference: Mark 3 points");
    
                % Allow the user to mark 3 points on the reference image
                points = cell(1,3);
                points{1} = drawpoint(app.ShiftUIAxes, 'Color','r'); % User marks 3 points
                points{2} = drawpoint(app.ShiftUIAxes, 'Color','g'); % User marks 3 points
                points{3} = drawpoint(app.ShiftUIAxes, 'Color','b'); % User marks 3 points
                
                points{1}.Position = round(points{1}.Position);
                points{2}.Position = round(points{2}.Position);
                points{3}.Position = round(points{3}.Position);

                app.ManualCorrectionXY = cell(1,size(app.HologramsRaw,3));

                app.ManualCorrectionXY{end} = { [points{1}.Position(1), points{1}.Position(2)],...
                                                [points{2}.Position(1), points{2}.Position(2)],...
                                                [points{3}.Position(1), points{3}.Position(2)]};
    
                correctedImages = app.HologramsPostPropagation(:,:, 1:end-1);
                cla(app.ShiftUIAxes); % Clear current axes

                for i = 1:size(correctedImages,3)
                    % Second subplot: Changing Image
                    currentImage = correctedImages(:,:,i);
                    app.DisplayShiftImage(currentImage);
                    title(app.ShiftUIAxes, sprintf('Image %d: Mark 3 Points', i));
    
                    points{1} = drawpoint(app.ShiftUIAxes, 'Color','r'); % User marks 3 points
                    points{2} = drawpoint(app.ShiftUIAxes, 'Color','g'); % User marks 3 points
                    points{3} = drawpoint(app.ShiftUIAxes, 'Color','b'); % User marks 3 points

                    points{1}.Position = round(points{1}.Position);
                    points{2}.Position = round(points{2}.Position);
                    points{3}.Position = round(points{3}.Position);
                
                    % Store the marked points for further processing (optional)
                    app.ManualCorrectionXY{i} =  { [points{1}.Position(1), points{1}.Position(2)],...
                                                   [points{2}.Position(1), points{2}.Position(2)],...
                                                   [points{3}.Position(1), points{3}.Position(2)]};
                
                    % Clear the second axes for the next image
                    cla(app.ShiftUIAxes); % Clear current axes
                end

                app.ShiftFilesListBox.Enable = "on";
                app.ShiftPreviousButton.Enable = "on";
                app.ShiftNextButton.Enable = "on";

                app.ManualcorrectButton.Text = "Finish correction";
                app.CurrentHologramIndex = 1;
                app.HandleHologramChange(app.CurrentHologramIndex);
            else
                cla(app.ShiftUIAxes);
                app.LoadXYTableData();
                image = app.HologramsPostPropagation(:,:, app.CurrentHologramIndex);
                title(app.ShiftUIAxes, sprintf('Image %d: Adjust Points', app.CurrentHologramIndex));

                position1 = app.ManualCorrectionXY{app.CurrentHologramIndex}{1};
                position2 = app.ManualCorrectionXY{app.CurrentHologramIndex}{2};
                position3 = app.ManualCorrectionXY{app.CurrentHologramIndex}{3};

                app.DisplayShiftImage(image);

                newPoint1 = drawpoint(app.ShiftUIAxes, 'Color', 'r', 'Position', position1); % User marks 3 points
                newPoint2 = drawpoint(app.ShiftUIAxes, 'Color', 'g', 'Position', position2); % User marks 3 points
                newPoint3 = drawpoint(app.ShiftUIAxes, 'Color', 'b', 'Position', position3); % User marks 3 points

                addlistener(newPoint1, 'ROIMoved', @app.UpdatePointListener);
                addlistener(newPoint2, 'ROIMoved', @app.UpdatePointListener);
                addlistener(newPoint3, 'ROIMoved', @app.UpdatePointListener);
            end
        end

        function DisplayShiftDefault(app)
            app.ShiftAmplitudeButton.Enable = "on";
            app.ShiftPhaseButton.Enable = "on";
            
            if(app.ShiftAmplitudeButton.Value == 1)
                app.ShiftRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
                app.ShiftRangeEndEditField.Value = app.AmplitudeDisplayRange(2);
            else
                app.ShiftRangeStartEditField.Value = app.PhaseDisplayRange(1);
                app.ShiftRangeEndEditField.Value = app.PhaseDisplayRange(2);
            end

            switch(app.ShiftHologramTypeDropDown.Value)
                case 'Reconstruction' %Reference 
                    image = app.HologramsPostPropagation(:,:,app.CurrentHologramIndex);
                case 'Holograms' % Holograms
                    app.ShiftAmplitudeButton.Enable = "off";
                    app.ShiftPhaseButton.Enable = "off";

                    image = app.HologramsRaw(:,:, app.CurrentHologramIndex);

                    imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.ShiftUIAxes);
                    return
                case 'Shifted Reconstruction' % Corrected Reference
                   if(~isempty(app.CorrectedReference))
                       image = app.CorrectedReference(:,:,app.CurrentHologramIndex); 
                   else
                       image = zeros(size(app.HologramsRaw(:,:,1)));
                   end 
                case 'Shifted Holograms' % Corrected Holograms
                   app.ShiftAmplitudeButton.Enable = "off";
                   app.ShiftPhaseButton.Enable = "off";

                   if(~isempty(app.CorrectedHolograms))
                       image = app.CorrectedHolograms(:,:,app.CurrentHologramIndex); 
                   else
                       image = zeros(size(app.HologramsRaw(:,:,1)));
                   end

                   imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.ShiftUIAxes);
                   return
            end

            app.DisplayShiftImage(image);
        end

        function DisplayShiftImage(app, image)
            if(app.ShiftAmplitudeButton.Value == 1)
                image = abs(image);
                imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.ShiftUIAxes);
            else
                image = angle(image);
                imshow(image, app.PhaseDisplayRange, "Parent", app.ShiftUIAxes);
            end
        end


        function DisplayReconstruction(app)

            if(app.ReconstructionAmplitudeButton.Value == 1)
                app.ReconstructionRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
                app.ReconstructionRangeEndEditField.Value = app.AmplitudeDisplayRange(2);
            else
                app.ReconstructionRangeStartEditField.Value = app.PhaseDisplayRange(1);
                app.ReconstructionRangeEndEditField.Value = app.PhaseDisplayRange(2);
            end

            if(size(app.FinalReconstructions, 1) == 0)
                return
            end

            complexImage = app.FinalReconstructions(:,:,app.CurrentHologramIndex);

            if(app.ReconstructionAmplitudeButton.Value == 1)
                image = abs(complexImage);
                imshow(image, app.AmplitudeDisplayRange, "Parent", app.ReconstructionUIAxes);
            else
                image = angle(complexImage);
                imshow(image, app.PhaseDisplayRange, "Parent", app.ReconstructionUIAxes);
            end

            if(app.ReconstructionUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.ReconstructionUIAxes.YLim(2) > size(app.HologramsRaw,1))
                app.ReconstructionUIAxes.XLim(2) = size(app.HologramsRaw,2);
                app.ReconstructionUIAxes.YLim(2) = size(app.HologramsRaw,1);
            end
        end

        function LoadHologramsListBox(app)
            if(~isempty(app.HologramsRawNames))
                
                for i = 1:length(app.HologramsRawNames)
                    %Names in the list box
                    fileName = app.HologramsRawNames{i}; 
                    FocusName = "AfterFocus" + fileName;
                    ShiftCorrectedReferenceName = "CorrectedAfterFocus" + fileName;
                    ShiftCorrectedHologramsName = "Corrected" + fileName;

                    app.HologramsPostPropagationNames = [app.HologramsPostPropagationNames, cellstr(FocusName)];
                    app.CorrectedReferenceNames = [app.CorrectedReferenceNames, cellstr(ShiftCorrectedReferenceName)];
                    app.CorrectedHologramsNames = [app.CorrectedHologramsNames, cellstr(ShiftCorrectedHologramsName)];
                end

                app.LoadDataFilesListBox.Items = app.HologramsRawNames;
                app.FocusFilesListBox.Items = app.HologramsPostPropagationNames;
                app.ShiftFilesListBox.Items = app.CorrectedReferenceNames;

                app.LoadDataFilesListBox.ItemsData = [1:length(app.Files)];
                app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
                app.FocusFilesListBox.ItemsData = [1:length(app.Files)];
                app.FocusFilesListBox.Value = app.CurrentHologramIndex;
                app.ShiftFilesListBox.ItemsData = [1:length(app.Files)];
                app.ShiftFilesListBox.Value = app.CurrentHologramIndex;
            end
        end

        function LoadShiftListBox(app)
             app.ShiftFilesListBox.Items=cellstr(num2str([]));
             switch(app.ShiftHologramTypeDropDown.Value)
                 case 'Reconstruction' %Reference
                    app.ShiftFilesListBox.Items = app.HologramsPostPropagationNames;
                 case 'Holograms' % Holograms
                    app.ShiftFilesListBox.Items = app.HologramsRawNames;
                 case 'Shifted Reconstruction' % Corrected Reference
                   app.ShiftFilesListBox.Items = app.CorrectedReferenceNames;
                 case 'Shifted Holograms' % Corrected Holograms
                   app.ShiftFilesListBox.Items = app.CorrectedHologramsNames;
             end

             app.LoadDataFilesListBox.ItemsData = [1:length(app.Files)];
             app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
        end

        function LoadReconstructionListBox(app)
            app.FinalReconstructionsNames = {} ;
            hologramCount = size(app.FinalReconstructions, 3);

            if(hologramCount == 0 || size(app.FinalReconstructions,1) == 0)
                return
            end

            for hologramIndex = 1:hologramCount
                reconstructionName = "ReconstructionS" + num2str(app.ReconstructionSigmas(hologramIndex)) + "I" + num2str(app.ReconstructionIterationNumber(hologramIndex));
                app.FinalReconstructionsNames  = [app.FinalReconstructionsNames cellstr(reconstructionName)];
            end

            app.ReconstructionHologramsListBox.Items = app.FinalReconstructionsNames;
            app.ReconstructionHologramsListBox.ItemsData = 1:hologramCount;
            app.ReconstructionHologramsListBox.Value = 1;
        end

        function LoadExportResultsAvailableHologramsListBox(app)
            app.AvailableHologramsListBox.Items = [app.HologramsRawNames app.HologramsPostPropagationNames app.CorrectedReferenceNames app.CorrectedHologramsNames app.FinalReconstructionsNames];
            
            hologramCounts = [length(app.HologramsRawNames) length(app.HologramsPostPropagationNames) length(app.CorrectedReferenceNames) length(app.CorrectedHologramsNames) length(app.FinalReconstructionsNames) ];

            listBoxIndexes = [];
            for hologramType = 1:length(hologramCounts)
                    singleTypeIndexes = [1:hologramCounts(hologramType)] + (hologramType-1)*1000;
                    listBoxIndexes = [listBoxIndexes singleTypeIndexes];
            end

            app.AvailableHologramsListBox.ItemsData = listBoxIndexes;
            
            if(~isempty(app.AvailableHologramsListBox.Value))
                app.AvailableHologramsListBox.Value = 1;
            end
        end

        function LoadExportResultsExportedHologramsListBox(app)
            app.ExportedHologramsListBox.Items = [app.HologramsRawNames app.HologramsPostPropagationNames app.CorrectedReferenceNames app.CorrectedHologramsNames app.FinalReconstructionsNames];

            hologramCounts = [length(app.HologramsRawNames) length(app.HologramsPostPropagationNames) length(app.CorrectedReferenceNames) length(app.CorrectedHologramsNames) length(app.FinalReconstructionsNames) ];

            listBoxIndexes = [];
            for hologramType = 1:length(hologramCounts)
                    singleTypeIndexes = [1:hologramCounts(hologramType)] + (hologramType-1)*1000;
                    listBoxIndexes = [listBoxIndexes singleTypeIndexes];
            end

            app.ExportedHologramsListBox.ItemsData = listBoxIndexes;
            
            if(~isempty(app.ExportedHologramsListBox.Value))
                app.ExportedHologramsListBox.Value = 1;
            end
        end

        function  [sortedItems, sortedItemsData] = SortListBoxByRisingValue(app, listbox)
            [sortedData,sortedIndexes] = sort(listbox.ItemsData);
            sortedItems = listbox.Items(sortedIndexes);
            sortedItemsData= sortedData;
        end
        
        function ExportHolograms(app, path)
            hologramCodes = app.ExportedHologramsListBox.ItemsData;
            hologramCount = numel(hologramCodes);

            hologramsRaw = 0;
            hologramsPostPropagation = 1;
            correctedReferenceHolograms = 2;
            correctedHolograms = 3;
            finalReconstructions = 4;
            
            for index = 1:hologramCount
                hologramType = round(hologramCodes(index)/1000);
                hologramIndex = mod(hologramCodes(index), 1000);

                switch(hologramType)
                    case hologramsRaw
                        hologram = app.HologramsRaw(:,:,hologramIndex);
                        hologramName = app.HologramsRawNames{hologramIndex};
                    case hologramsPostPropagation
                        hologram = app.HologramsPostPropagation(:,:,hologramIndex);
                        hologramName = app.HologramsPostPropagationNames{hologramIndex};
                    case correctedReferenceHolograms
                        hologram = app.CorrectedReference(:,:,hologramIndex);
                        hologramName = app.CorrectedReferenceNames{hologramIndex};
                    case correctedHolograms
                        hologram = app.CorrectedHolograms(:,:,hologramIndex);
                        hologramName = app.CorrectedHologramsNames{hologramIndex};
                    case finalReconstructions
                        hologram = app.FinalReconstructions(:,:,hologramIndex);
                        hologramName = app.FinalReconstructionsNames{hologramIndex};
                end

                format = app.FormatDropDown.Value;
                if(format == ".png")
                    if(ismember(hologramType, [hologramsRaw correctedHolograms]))
                        hologramName = path +"\"+ hologramName + ".png";
                        hologram = uint8(hologram);
                        imwrite(hologram, hologramName);
                    elseif(ismember(hologramType, [hologramsPostPropagation correctedReferenceHolograms finalReconstructions]))
                        hologramAmplitudeName = path +"\"+ hologramName + "Amplitude.png";
                        hologramPhaseName = path +"\"+ hologramName + "Phase.png";
                        
                        exportMode = app.ExportModeDropDown.Value;

                        if(exportMode == "Amplitude") 
                            hologramAmplitude = uint8(abs(hologram));
                            imwrite(hologramAmplitude, hologramAmplitudeName);
                        elseif(exportMode == "Phase")
                            hologramPhase = uint8(angle(hologram));
                            imwrite(hologramPhase, hologramPhaseName)
                        elseif(exportMode == "Both")
                            hologramAmplitude = uint8(abs(hologram));
                            hologramPhase = uint8(angle(hologram));

                            imwrite(hologramAmplitude, hologramAmplitudeName);
                            imwrite(hologramPhase, hologramPhaseName)
                        end
                    end
                elseif(format == ".mat")
                    hologramName = path + "\" + hologramName + ".mat";
                    save(hologramName, "hologram");
                end
            end
        end

        function FocusROIUpdateRectangleListener(app, src, event)
            eventName = event.EventName;
            
            switch(eventName)
                case{'ROIMoved'}
                    src.Position = round(event.CurrentPosition);
                    app.FocusROIRectanglePosition = round(event.CurrentPosition);
            end
        end
        
        function UpdatePointListener(app, src, event)
            eventName = event.EventName;
            
            pointsStruct = app.ManualCorrectionXY{app.CurrentHologramIndex};

            for pointIndex = 1:3
                if(pointsStruct{pointIndex} == event.PreviousPosition)
                    pointsStruct{pointIndex} = round(event.CurrentPosition);
                    src.Position = round(event.CurrentPosition);
                    break;
                end
            end
            
            switch(eventName)
                case{'ROIMoved'}
                    app.ManualCorrectionXY{app.CurrentHologramIndex} = pointsStruct;
            end

            app.LoadXYTableData();
        end

        function DisableInterface(app)
            % Menu buttons
            app.DisableMenu();
            
            % Load Data
            app.LoadDataFilesListBox.Enable = "off";
            app.LoadHologramsButton.Enable = "off";
            app.PixelSizeEditField.Enable = "off";
            app.HeightEditField.Enable = "off";
            app.ModeDropDown.Enable = "off";
            app.PhotoWavelengthTable.Enable =  "off";
            app.PhotoHeightTable.Enable = "off";

            app.LoadDataNextButton.Enable = "off";
            app.LoadDataPreviousButton.Enable = "off";

            % Focus 
            app.FocusFilesListBox.Enable = "off";
            app.StepEditField.Enable = "off";
            app.CurrentHeightSubtractButton.Enable = "off";
            app.CurrentHeightEditField.Enable = "off";
            app.CurrentHeightAddButton.Enable = "off";

            app.FocusAmplitudeButton.Enable = "off";
            app.FocusPhaseButton.Enable = "off";
            app.FocusNextButton.Enable = "off";
            app.FocusPreviousButton.Enable = "off";
        end

        function DisableMenu(app)
            app.LoadDataButton.Enable = "off";
            app.FocusButton.Enable = "off";
            app.ShiftButton.Enable = "off";
            app.ReconstructionButton.Enable = "off";
            app.ExportResultsButton.Enable = "off";
        end
        
        function EnableInterface(app)
            % Menu buttons
            app.EnableMenu();

            % Load Data
            app.LoadDataFilesListBox.Enable = "on";
            app.LoadHologramsButton.Enable = "on";
            app.PixelSizeEditField.Enable = "on";
            app.HeightEditField.Enable = "on";
            app.ModeDropDown.Enable = "on";
            app.PhotoWavelengthTable.Enable =  "on";
            app.PhotoHeightTable.Enable = "on";

            app.LoadDataNextButton.Enable = "on";
            app.LoadDataPreviousButton.Enable = "on";

            % Focus and shift
            app.FocusFilesListBox.Enable = "on";
            app.StepEditField.Enable = "on";
            app.CurrentHeightSubtractButton.Enable = "on";
            app.CurrentHeightEditField.Enable = "on";
            app.CurrentHeightAddButton.Enable = "on";

            app.FocusAmplitudeButton.Enable = "on";
            app.FocusPhaseButton.Enable = "on";
            app.FocusNextButton.Enable = "on";
            app.FocusPreviousButton.Enable = "on";
        end

        function EnableMenu(app)
            app.LoadDataButton.Enable = "on";
            app.FocusButton.Enable = "on";
            app.ShiftButton.Enable = "on";
            app.ReconstructionButton.Enable = "on";
            app.ExportResultsButton.Enable = "on";
        end

        function SaveAppState(app)
            state = struct();
            props = properties(app);

            for pointIndex = 1:length(props)
                name = props{pointIndex};
                component = app.(name);  % May be a component or a property, so far...
                componentClass = string(class(component));
                if componentClass == "matlab.ui.control.NumericEditField" ...
                        || componentClass == "matlab.ui.control.EditField" ...
                        || componentClass == "matlab.ui.control.CheckBox"
                    state.(name)= component.Value;
                elseif componentClass == "matlab.ui.control.DropDown"
                    % These are weird, had to be specially handled to save a string
                    % instead of an index into the list:
                    v = component.Value;
                    if  ~isempty(v)
                        id = find(strcmp(component.Items,v));
                        state.(name)= component.Items{id};
                    end
                elseif componentClass == "matlab.ui.container.CheckBoxTree"
                    for ii = 1:size(component.CheckedNodes,1)
                        tag(ii) = string(app.(name).CheckedNodes(ii).Tag);
                    end
                    if exist('tag','var')
                        state.(name) = tag;
                    end
                elseif componentClass == "matlab.ui.control.ListBox" 
                    state.(name) = component.Items;
                elseif componentClass == "matlab.ui.control.Table"
                    state.(name) = component.Data;
                end 
            end

            mc= ?HoloApp;
            privateProps = mc.PropertyList;

            for pointIndex = (length(props) + 1):(length(privateProps) - 4)
                name = privateProps(pointIndex).Name;
                componentClass = app.(name);
                state.(name) = componentClass;
            end
            
            all = 0;

            state.QCIHolograms = app.QCIModel.getHolograms(all);
            state.QCIWavelengths = app.QCIModel.getWavelengths(all);
            state.QCIZCoordinates = app.QCIModel.getZCoordinates(all);
            
            savePath = app.Files(1).folder + "\SavedSettings.mat";
            save(savePath, 'state', '-v7.3');
        end

        function LoadAppState(app, loadPath)
            load(loadPath, 'state');
            fields = fieldnames(state);
            props = properties(app);

            for pointIndex = 1:length(props)
                name = fields{pointIndex};
                try   % or could use isprop(app, name) to test presence
                        comp = app.(name);
                    if class(comp) == "matlab.ui.container.CheckBoxTree"
                        tags = state.(name);
                        for ii = 1:size(tags,2)
                            checkedNodes(ii) = findall(0,'tag',tags(ii));
                        end
                        app.(name).CheckedNodes = checkedNodes;
                    elseif class(comp) == "matlab.ui.control.ListBox"
                        comp.Items = state.(name);
                    elseif class(comp) == "matlab.ui.control.Table"
                        comp.Data = state.(name)
                    else
                        comp.Value = state.(name);
                    end
                catch err
                    disp(['Did not use saved field', name, ': ', err.identifier])
                end
            end
            
            QCIModelDataCount = 3;

            for pointIndex = (length(props)+1):(length(fields) - QCIModelDataCount)
                name = fields{pointIndex};
                app.(name) = state.(name);
            end
            
            if(~isempty(state.QCIHolograms))
                hologramIndexes = 1:size(state.QCIHolograms,3);
                app.QCIModel.setHolograms(state.QCIHolograms, hologramIndexes);
            end

            if(~isempty(state.QCIWavelengths))
                hologramIndexes = 1:length(state.QCIWavelengths);
                app.QCIModel.setWavelengths(state.QCIWavelengths, hologramIndexes);
            end

            if(~isempty(state.QCIZCoordinates))
                hologramIndexes = 1:length(state.QCIZCoordinates);
                app.QCIModel.setZCoordinates(state.QCIZCoordinates, hologramIndexes);
            end

        end
        
    end
    %Controller

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: NavigationTab
        function NavigationTabSelectionChanged(app, event)
            if(app.LoadDataButton.Value == 1)
                app.NavigationTab.SelectedTab = app.LoadDataTab;
            elseif(app.FocusButton.Value == 1)
                app.NavigationTab.SelectedTab = app.FocusTab;
            elseif(app.ShiftButton.Value == 1)
                app.NavigationTab.SelectedTab = app.ShiftTab;
            elseif(app.ReconstructionButton.Value == 1)
                app.NavigationTab.SelectedTab = app.ReconstructionTab;
            elseif(app.ExportResultsButton.Value == 1)
                app.NavigationTab.SelectedTab = app.ResultsTab;
            end
        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
           app.NavigationTab.SelectedTab = app.LoadDataTab;
           app.FocusButton.Value = 0;
           app.ShiftButton.Value = 0;
           app.ExportResultsButton.Value = 0;
           app.ReconstructionButton.Value = 0;
           
           %Load the current propagation image;
           app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: FocusButton
        function FocusButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.FocusTab;
            app.LoadDataButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ExportResultsButton.Value = 0;
            app.ReconstructionButton.Value = 0;

            %Load the current propagation image;
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: ShiftButton
        function ShiftButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.ShiftTab;
            app.LoadDataButton.Value = 0;
            app.FocusButton.Value = 0;
            app.ReconstructionButton.Value = 0;
            app.ExportResultsButton.Value = 0;
            
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: ReconstructionButton
        function ReconstructionButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.ReconstructionTab;
            app.LoadDataButton.Value = 0;
            app.FocusButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ExportResultsButton.Value = 0;
            
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: ExportResultsButton
        function ExportResultsButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.ResultsTab;
            app.LoadDataButton.Value = 0;
            app.FocusButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ReconstructionButton.Value = 0;

            if(isempty(app.ExportedHologramsListBox.Items) && isempty(app.AvailableHologramsListBox.Items))
                app.LoadExportResultsAvailableHologramsListBox();
            end
        end

        % Button pushed function: LoadHologramsButton
        function LoadHologramsButtonPushed(app, event)
             %app.LoadAppState();
             selectedPath = uigetdir();

             app.DisableInterface();

             %% Load state from SaveSettings.mat
             % FileList = dir(fullfile(selectedPath, 'SavedSettings.mat'));
             % 
             % if(~isempty(FileList))
             %     path = selectedPath + "\SavedSettings.mat";
             %     app.LoadAppState(path)
             % 
             %     image = app.QCIModel.getHolograms(app.CurrentHologramIndex);
             %     imshow(uint8(image), "Parent",app.LoadDataUIAxes);
             % 
             %     app.EnableInterface();
             %     return
             % end

             %Load photos to the app.
             if(selectedPath ~= 0)
                 app.Files = [];
                 files = dir(selectedPath);
                 files(1:2) = [];

                 filtred = zeros(1,length(files));
                 for i = 1:length(files)
                     if (contains(files(i).name,".mat"))
                         filtred(i) = 1;
                     end

                     if (contains(files(i).name,".png"))
                         filtred(i) = 1;
                     end

                     if (contains(files(i).name,".tif"))
                         filtred(i) = 1;
                     end
                 end

                 files = files(filtred == 1);

                 %Model
                 app.Files = files;
                 prepareApp = HoloPrep(app);

                 while isvalid(prepareApp); pause(0.1); end

                 app.LoadHologramsToQCIModel();
                 app.CurrentHologramIndex = 1;

                 % View
                 app.LoadHologramsListBox();
                 app.LoadModeTableData();

                app.HandleHologramChange(app.CurrentHologramIndex);
             end

             app.EnableInterface();
        end

        % Value changed function: LoadDataFilesListBox
        function LoadDataFilesListBoxValueChanged(app, event)
            app.HandleHologramChange(app.LoadDataFilesListBox.Value);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            HandleModeChange(app, app.ModeDropDown.Value);
        end

        % Value changed function: FocusFilesListBox
        function FocusFilesListBoxValueChanged(app, event)
            app.HandleHologramChange(app.FocusFilesListBox.Value);
        end

        % Value changed function: FocusRangeStartEditField, 
        % ...and 3 other components
        function RangeStartEditFieldValueChanged(app, event)

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                app.AmplitudeDisplayRange(1) = app.LoadDataRangeStartEditField.Value;
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                if(app.FocusAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.FocusRangeStartEditField.Value;
                else
                    app.PhaseDisplayRange(1) = app.FocusRangeStartEditField.Value;
                end
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                if(app.ShiftAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.ShiftRangeStartEditField.Value;
                else
                    app.PhaseDisplayRange(1) = app.ShiftRangeStartEditField.Value;
                end
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                if(app.ReconstructionAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.ReconstructionRangeStartEditField.Value;
                else
                    app.PhaseDisplayRange(1) = app.ReconstructionRangeStartEditField.Value;
                end
            end

            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: FocusRangeEndEditField, 
        % ...and 3 other components
        function RangeEndEditFieldValueChanged(app, event)
            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                app.AmplitudeDisplayRange(2) = app.LoadDataRangeEndEditField.Value;
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                if(app.FocusAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.FocusRangeEndEditField.Value;
                else
                    app.PhaseDisplayRange(2) = app.FocusRangeEndEditField.Value;
                end
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                if(app.ShiftAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.ShiftRangeEndEditField.Value;
                else
                    app.PhaseDisplayRange(2) = app.ShiftRangeEndEditField.Value;
                end
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                if(app.ReconstructionAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.ReconstructionRangeEndEditField.Value;
                else
                    app.PhaseDisplayRange(2) = app.ReconstructionRangeEndEditField.Value;
                end
            end

            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Button pushed function: FocusNextButton, LoadDataNextButton, 
        % ...and 2 other components
        function NextButtonValueChanged(app, event)
             app.HandleHologramChange(app.CurrentHologramIndex + 1);
        end

        % Button pushed function: FocusPreviousButton, 
        % ...and 3 other components
        function PreviousButtonValueChanged(app, event)
            app.HandleHologramChange(app.CurrentHologramIndex - 1);
        end

        % Value changed function: FocusAmplitudeButton
        function FocusAmplitudeButtonValueChanged(app, event)
            app.FocusPhaseButton.Value = ~app.FocusAmplitudeButton.Value;
            app.FocusAmplitudeButton.Enable = "off";
            app.FocusPhaseButton.Enable = "on";

            app.FocusRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
            app.FocusRangeEndEditField.Value = app.AmplitudeDisplayRange(2);

            app.DisplayFocus();
        end

        % Value changed function: FocusPhaseButton
        function FocusPhaseButtonValueChanged(app, event)
            app.FocusAmplitudeButton.Value = ~app.FocusPhaseButton.Value;
            app.FocusPhaseButton.Enable = "off";
            app.FocusAmplitudeButton.Enable = "on";

            app.FocusRangeStartEditField.Value = app.PhaseDisplayRange(1);
            app.FocusRangeEndEditField.Value = app.AmplitudeDisplayRange(2);

            app.DisplayFocus();
        end

        % Value changed function: ShiftAmplitudeButton
        function ShiftAmplitudeButtonValueChanged(app, event)
            app.ShiftPhaseButton.Value = ~app.ShiftAmplitudeButton.Value;
            app.ShiftAmplitudeButton.Enable = "off";
            app.ShiftPhaseButton.Enable = "on";

            app.ShiftRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
            app.ShiftRangeEndEditField.Value = app.AmplitudeDisplayRange(2);

            app.DisplayShift();
        end

        % Value changed function: ShiftPhaseButton
        function ShiftPhaseButtonValueChanged(app, event)
            app.ShiftAmplitudeButton.Value = ~app.ShiftPhaseButton.Value;
            app.ShiftAmplitudeButton.Enable = "on";
            app.ShiftPhaseButton.Enable = "off";

            app.ShiftRangeStartEditField.Value = app.PhaseDisplayRange(1);
            app.ShiftRangeEndEditField.Value = app.PhaseDisplayRange(2);
            
            app.DisplayShift();
        end

        % Value changed function: ReconstructionAmplitudeButton
        function ReconstructionAmplitudeButtonValueChanged(app, event)
            app.ReconstructionPhaseButton.Value = ~app.ReconstructionAmplitudeButton.Value;
            app.ReconstructionAmplitudeButton.Enable = "off";
            app.ReconstructionPhaseButton.Enable = "on";

            app.ReconstructionRangeStartEditField.Value = app.AmplitudeDisplayRange(1);
            app.ReconstructionRangeEndEditField.Value = app.AmplitudeDisplayRange(2);

            app.DisplayReconstruction();
        end

        % Value changed function: ReconstructionPhaseButton
        function ReconstructionPhaseButtonValueChanged(app, event)
            app.ReconstructionAmplitudeButton.Value = ~app.ReconstructionPhaseButton.Value;
            app.ReconstructionAmplitudeButton.Enable = "on";
            app.ReconstructionPhaseButton.Enable = "off";

            app.ReconstructionRangeStartEditField.Value = app.PhaseDisplayRange(1);
            app.ReconstructionRangeEndEditField.Value = app.PhaseDisplayRange(2);

            app.DisplayReconstruction();
        end

        % Cell edit callback: PhotoWavelengthTable
        function PhotoWavelengthTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            all=0;
            newWavelengths = app.QCIModel.getWavelengths(all);
            newWavelengths(indices(1)) = newData;

            app.QCIModel.setWavelenghts(newWavelengths);
        end

        % Cell edit callback: PhotoHeightTable
        function PhotoHeightTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            all = 0;
            newHeights = app.QCIModel.getZCoordinates(all);
            newHeights(indices(1)) = newData;

            app.QCIModel.setZCoordinates(newHeights,1:size(app.HologramsRaw,3));
        end

        % Value changed function: CurrentHeightAddButton
        function CurrentHeightAddButtonValueChanged(app, event)
            %View
            app.CurrentHeightEditField.Value =  app.CurrentHeightEditField.Value + app.StepEditField.Value;

            %Model
            all = 0;
            heights = app.QCIModel.getZCoordinates(all);
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;
            app.QCIModel.setZCoordinates(heights);

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: CurrentHeightSubtractButton
        function CurrentHeightSubtractButtonValueChanged(app, event)
            %View
            app.CurrentHeightEditField.Value =  app.CurrentHeightEditField.Value - app.StepEditField.Value;
            %Model
            all = 0;
            heights = app.QCIModel.getZCoordinates(all);
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;
            app.QCIModel.setZCoordinates(heights);

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: CurrentHeightEditField
        function CurrentHeightEditFieldValueChanged(app, event)
            %Model
            all = 0;
            app.DeterminedHeights = app.QCIModel.getZCoordinates(all);
            app.DeterminedHeights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;
            app.QCIModel.setZCoordinates(app.DeterminedHeights);

            app.SweepStartEditField.Value = app.CurrentHeightEditField.Value;

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Button pushed function: AutocorrectButton
        function AutocorrectButtonPushed(app, event)
            all = 0;
            [app.CorrectedReference, app.CorrectedHolograms, app.CorrectedZCoordinates, ~] = app.QCIModel.automaticalShiftAndScalingCorrection(app.HologramsPostPropagation);
            
            app.DeterminedHeights = app.QCIModel.getZCoordinates(all);
            
            app.HeightCorrectionTable.Data = [app.DeterminedHeights.' app.CorrectedZCoordinates.'];
            
            allIndexes =  [1:size(app.CorrectedHolograms,3)];
            app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
            app.QCIModel.setZCoordinates(app.CorrectedZCoordinates, allIndexes);

            for index = allIndexes 
                app.CorrectedReference(:,:,index) = app.QCIModel.propagate(index);
            end
        end

        % Button pushed function: ManualcorrectButton
        function ManualcorrectButtonPushed(app, event)
            if(app.IsShiftManualCorrectionOn)
                % Enable controls
                app.EnableMenu();
                app.ShiftFilesListBox.Enable = "on";
                app.AutocorrectButton.Enable = "on";
                app.ShiftPreviousButton.Enable = "on";
                app.ShiftNextButton.Enable = "on";

                if(app.ManualcorrectButton.Text == "Finish correction")
                    [app.CorrectedReference, app.CorrectedHolograms, app.CorrectedZCoordinates] = app.QCIModel.ThreePointShiftAndScalingCorrection(app.HologramsPostPropagation, app.ManualCorrectionXY);
                    
                    all = 0;
                    app.DeterminedHeights = app.QCIModel.getZCoordinates(all);
            
                    app.HeightCorrectionTable.Data = [app.DeterminedHeights.' app.CorrectedZCoordinates.'];
                    
                    allIndexes =  [1:size(app.CorrectedHolograms,3)];
                    app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
                    app.QCIModel.setZCoordinates(app.CorrectedZCoordinates, allIndexes);

                    for index = allIndexes 
                        app.CorrectedReference(:,:,index) = app.QCIModel.propagate(index);
                    end
                end

                app.CurrentHologramIndex = 1;
                app.ManualCorrectionXY = {};
                title(app.ShiftUIAxes,"");
                app.ManualcorrectButton.Text = "Manual Correction";
            else
                app.DisableMenu();
                app.ShiftFilesListBox.Enable = "off";
                app.AutocorrectButton.Enable = "off";
                app.ShiftPreviousButton.Enable = "off";
                app.ShiftNextButton.Enable = "off";
                

                app.ShiftHologramTypeDropDown.Value = "Reconstruction";
                app.ManualcorrectButton.Text = "Cancel correction";
            end

            app.IsShiftManualCorrectionOn = ~app.IsShiftManualCorrectionOn;
            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: ShiftHologramTypeDropDown
        function ShiftHologramTypeDropDownValueChanged(app, event)
             app.LoadShiftListBox();
             app.ShiftFilesListBox.Value = app.CurrentHologramIndex;
             app.DisplayShift();
        end

        % Button pushed function: StartReconstruction
        function StartReconstructionButtonPushed(app, event)
            sigma = app.SigmaEditField.Value;
            iterationNumber = app.IterationNumberEditField.Value;
            
            reconstruction = app.QCIModel.IGA(iterationNumber, sigma);
            app.ReconstructionSigmas(end + 1) = sigma;
            app.ReconstructionIterationNumber(end + 1) = iterationNumber;

            if(size(app.FinalReconstructions, 1) == 0)
                app.FinalReconstructions(:,:, end) = reconstruction;
            else
                app.FinalReconstructions(:,:, end+1) = reconstruction;
            end

            app.LoadReconstructionListBox();
            app.CurrentHologramIndex = size(app.FinalReconstructions,3);
            app.DisplayReconstruction();
        end

        % Button pushed function: StepSubtractButton
        function StepSubtractButtonValueChanged(app, event)
            if(app.StepEditField.Value/2 > 1)
                app.StepEditField.Value = round(app.StepEditField.Value/2);
            else
                app.StepEditField.Value = 1;
            end
        end

        % Button pushed function: StepAddButton
        function StepAddButtonValueChanged(app, event)
            app.StepEditField.Value = app.StepEditField.Value*2;
        end

        % Button pushed function: MoveAllLeft
        function MoveAllLeftButtonPushed(app, event)
            app.LoadExportResultsAvailableHologramsListBox();

            % Clear exported holograms list box
            app.ExportedHologramsListBox.Items = {};
            app.ExportedHologramsListBox.ItemsData = [];
        end

        % Button pushed function: MoveSingleLeft
        function MoveSingleLeftButtonPushed(app, event)
            [~,selectedExportedIndex] = ismember(app.ExportedHologramsListBox.Value, app.ExportedHologramsListBox.ItemsData);
            app.AvailableHologramsListBox.Items = [app.AvailableHologramsListBox.Items app.ExportedHologramsListBox.Items(selectedExportedIndex)];

            app.ExportedHologramsListBox.Items(selectedExportedIndex) = [];
            app.ExportedHologramsListBox.ItemsData(selectedExportedIndex) = [];

            if(length(app.AvailableHologramsListBox.Items) > 1)
                [app.AvailableHologramsListBox.Items, app.AvailableHologramsListBox.ItemsData] = app.SortListBoxByRisingValue(app.AvailableHologramsListBox);
            end
        end

        % Button pushed function: MoveSingleRight
        function MoveSingleRightButtonPushed(app, event)
            [~,selectedAvailableIndex] = ismember(app.AvailableHologramsListBox.Value, app.AvailableHologramsListBox.ItemsData);

            app.ExportedHologramsListBox.Items = [app.ExportedHologramsListBox.Items app.AvailableHologramsListBox.Items(selectedAvailableIndex)];
            app.ExportedHologramsListBox.ItemsData(end+1) = app.AvailableHologramsListBox.ItemsData(selectedAvailableIndex);
            
            app.AvailableHologramsListBox.Items(selectedAvailableIndex) = [];
            app.AvailableHologramsListBox.ItemsData(selectedAvailableIndex) = [];

            if(length(app.ExportedHologramsListBox.Items) > 1)
                [app.ExportedHologramsListBox.Items, app.ExportedHologramsListBox.ItemsData] = app.SortListBoxByRisingValue(app.ExportedHologramsListBox);
            end
        end

        % Button pushed function: MoveAllRight
        function MoveAllRightButtonPushed(app, event)
            app.LoadExportResultsExportedHologramsListBox();

            % Clear available holograms list box
            app.AvailableHologramsListBox.Items = {};
            app.AvailableHologramsListBox.ItemsData = [];
        end

        % Button pushed function: ExporthologramsButton
        function ExporthologramsButtonPushed(app, event)
            pathToFiles = app.Files(1).folder;
            currentDate = replace(erase(string(datetime),":")," ", "_");
            pathToResultsFolder = pathToFiles + "\Results_" + currentDate;
            mkdir(pathToResultsFolder);

            app.ExportHolograms(pathToResultsFolder);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.SaveAppState();
            delete(app)
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            start = app.SweepStartEditField.Value;
            range = app.SweepRangeEditField.Value;
            step = app.SweepStepEditField.Value;

            % Y axis faces down, X axis faces right
            xlim = round(app.FocusUIAxes.XLim);
            ylim = round(app.FocusUIAxes.YLim);

            range = [start-range:step:start+range]; 
            ROI = [xlim(1), ylim(1), xlim(2)-xlim(1), ylim(2)-ylim(1)];
            darkVolumeROI = app.FocusROIRectanglePosition;
            [betterZ, ~,~] = app.QCIModel.DarkFocus(app.CurrentHologramIndex, range, ROI, darkVolumeROI);

            app.QCIModel.setZCoordinates(betterZ, app.CurrentHologramIndex);
            app.CurrentHeightEditField.Value = betterZ;
            app.SweepStartEditField.Value = betterZ;

            app.DisplayFocus();      
        end

        % Value changed function: InspectROICheckBox
        function InspectROICheckBoxValueChanged(app, event)
            app.DisplayFocus();
            if(~app.InspectROICheckBox.Value)
                app.FocusROIRectanglePosition = 0;
            end
        end

        % Callback function
        function LoadButtonPushed(app, event)
             app.LoadHologramsToQCIModel();
             app.CurrentHologramIndex = 1;

             % View
             app.LoadHologramsListBox();
             app.LoadModeTableData();
            
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: GPUProcessingCheckBox
        function GPUProcessingCheckBoxValueChanged(app, event)
            app.GPUProcessingStatus = app.GPUProcessingCheckBox.Value;

            all = 0;
            holograms = app.QCIModel.getHolograms(all);

            if(~isempty(holograms) )
                names = app.QCIModel.getNames(all);
                wavelengths = app.QCIModel.getWavelengths(all);
                zCoordiantes = app.QCIModel.getZCoordinates(all);
                pixelSize = app.PixelSizeEditField.Value;

                if(app.GPUProcessingStatus)
                    app.QCIModel = QCI_Model_GPU(names, holograms, wavelengths, zCoordiantes, pixelSize); 
                else
                    app.QCIModel = QCI_Model(names, holograms, wavelengths, zCoordiantes, pixelSize); 
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BackLayout = uigridlayout(app.UIFigure);
            app.SideLayout = uigridlayout(app.BackLayout);
            app.MainLayout = uigridlayout(app.SideLayout);
            app.NavigationTab = uitabgroup(app.MainLayout);
            app.NavigationTab.SelectionChangedFcn = createCallbackFcn(app, @NavigationTabSelectionChanged, true);
            app.LoadDataTab = uitab(app.NavigationTab);
            app.LoadDataTab.Title = 'Load Data';
            app.LoadDataLayout = uigridlayout(app.LoadDataTab);
            app.LoadDataControlLayout = uigridlayout(app.LoadDataLayout);
            app.LoadDataFilesPanel = uipanel(app.LoadDataControlLayout);
            app.LoadDataFileListBoxLayout = uigridlayout(app.LoadDataFilesPanel);
            app.HologramsLabel = uilabel(app.LoadDataFileListBoxLayout);
            app.HologramsLabel.Text = 'Holograms';
            app.LoadDataFilesListBox = uilistbox(app.LoadDataFileListBoxLayout);
            app.LoadDataFilesListBox.Items = {};
            app.LoadDataFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadDataFilesListBoxValueChanged, true);
            app.LoadDataFilesListBox.Value = {};
            app.LoadHologramsButton = uibutton(app.LoadDataControlLayout, 'push');
            app.LoadHologramsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsButtonPushed, true);
            app.LoadHologramsButton.Text = 'Load Holograms';
            app.GridLayout = uigridlayout(app.LoadDataControlLayout);
            app.DefaultvaluesLabel = uilabel(app.GridLayout);
            app.DefaultvaluesLabel.Text = 'Default values';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout);
            app.ModeDropDown.Items = {'Single hologram', 'Multi-wavelenght', 'Multi-height'};
            app.ModeDropDown.ItemsData = {'Single hologram', 'Multi-wavelenght', 'Multi-height'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Single hologram';
            app.ModeLabel = uilabel(app.GridLayout);
            app.ModeLabel.Text = 'Mode';
            app.ModeTabGroup = uitabgroup(app.GridLayout);
            app.ModeTabGroup.Visible = 'off';
            app.MultiwavelenghtTab = uitab(app.ModeTabGroup);
            app.MultiwavelenghtTab.Title = 'Multi-wavelenght';
            app.MultiwavelengthLayout = uigridlayout(app.MultiwavelenghtTab);
            app.PhotoWavelengthTable = uitable(app.MultiwavelengthLayout);
            app.PhotoWavelengthTable.ColumnName = {'Photo'; 'Wavelength [μm]'};
            app.PhotoWavelengthTable.RowName = {};
            app.PhotoWavelengthTable.ColumnEditable = [false true];
            app.PhotoWavelengthTable.CellEditCallback = createCallbackFcn(app, @PhotoWavelengthTableCellEdit, true);
            app.MultiheightTab = uitab(app.ModeTabGroup);
            app.MultiheightTab.Title = 'Multi-height';
            app.MultiheightLayout = uigridlayout(app.MultiheightTab);
            app.PhotoHeightTable = uitable(app.MultiheightLayout);
            app.PhotoHeightTable.ColumnName = {'Photo'; 'Height [μm]'};
            app.PhotoHeightTable.RowName = {};
            app.PhotoHeightTable.ColumnEditable = [false true];
            app.PhotoHeightTable.CellEditCallback = createCallbackFcn(app, @PhotoHeightTableCellEdit, true);
            app.HeightmLabel = uilabel(app.GridLayout);
            app.HeightmLabel.Text = 'Height           [μm]';
            app.HeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.PixelSizemLabel = uilabel(app.GridLayout);
            app.PixelSizemLabel.Text = 'Pixel Size      [μm]';
            app.PixelSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelSizeEditField.Limits = [1 Inf];
            app.PixelSizeEditField.Value = 2.4;
            app.WavelengthmEditFieldLabel = uilabel(app.GridLayout);
            app.WavelengthmEditFieldLabel.Text = 'Wavelength [μm]';
            app.WavelengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.Value = 0.561;
            app.LoadDataDisplayLayout = uigridlayout(app.LoadDataLayout);
            app.LoadDataUIAxes = uiaxes(app.LoadDataDisplayLayout);
            app.LoadDataUIAxes.XTick = [];
            app.LoadDataUIAxes.YTick = [];
            app.LoadDataUIAxes.ZTick = [];
            app.LoadDataDisplayButtonsLayout = uigridlayout(app.LoadDataDisplayLayout);
            app.LoadDataPreviousButton = uibutton(app.LoadDataDisplayButtonsLayout, 'push');
            app.LoadDataPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.LoadDataPreviousButton.Text = '<';
            app.LoadDataNextButton = uibutton(app.LoadDataDisplayButtonsLayout, 'push');
            app.LoadDataNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.LoadDataNextButton.Text = '>';
            app.LoadDataGraphSettingsLayout = uigridlayout(app.LoadDataDisplayLayout);
            app.ValuerangeEditField_2Label = uilabel(app.LoadDataGraphSettingsLayout);
            app.ValuerangeEditField_2Label.Text = 'Value range:';
            app.LoadDataRangeStartEditField = uieditfield(app.LoadDataGraphSettingsLayout, 'numeric');
            app.LoadDataRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.EditFieldLabel_2 = uilabel(app.LoadDataGraphSettingsLayout);
            app.EditFieldLabel_2.Text = '-';
            app.LoadDataRangeEndEditField = uieditfield(app.LoadDataGraphSettingsLayout, 'numeric');
            app.LoadDataRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.LoadDataRangeEndEditField.Value = 255;
            app.FocusTab = uitab(app.NavigationTab);
            app.FocusTab.Title = 'Focus';
            app.FocusLayout = uigridlayout(app.FocusTab);
            app.FocusControlLayout = uigridlayout(app.FocusLayout);
            app.FocusFilesPanel = uipanel(app.FocusControlLayout);
            app.FocusFileListBoxLayout = uigridlayout(app.FocusFilesPanel);
            app.HologramsLabel_2 = uilabel(app.FocusFileListBoxLayout);
            app.HologramsLabel_2.Text = 'Holograms';
            app.FocusFilesListBox = uilistbox(app.FocusFileListBoxLayout);
            app.FocusFilesListBox.Items = {};
            app.FocusFilesListBox.ValueChangedFcn = createCallbackFcn(app, @FocusFilesListBoxValueChanged, true);
            app.FocusFilesListBox.Value = {};
            app.FocusingPanel = uipanel(app.FocusControlLayout);
            app.AdjustFocusLayout = uigridlayout(app.FocusingPanel);
            app.StepControlLayout = uigridlayout(app.AdjustFocusLayout);
            app.StepLabel = uilabel(app.StepControlLayout);
            app.StepLabel.Text = 'Step [μm]';
            app.StepEditField = uieditfield(app.StepControlLayout, 'numeric');
            app.StepEditField.Limits = [1 Inf];
            app.StepEditField.Value = 100;
            app.StepSubtractButton = uibutton(app.StepControlLayout, 'push');
            app.StepSubtractButton.ButtonPushedFcn = createCallbackFcn(app, @StepSubtractButtonValueChanged, true);
            app.StepSubtractButton.Text = '-';
            app.StepAddButton = uibutton(app.StepControlLayout, 'push');
            app.StepAddButton.ButtonPushedFcn = createCallbackFcn(app, @StepAddButtonValueChanged, true);
            app.StepAddButton.Text = '+';
            app.CurrentHeightControlLayout = uigridlayout(app.AdjustFocusLayout);
            app.CurrentHeightmLabel = uilabel(app.CurrentHeightControlLayout);
            app.CurrentHeightmLabel.Text = 'Current Height [μm]';
            app.CurrentHeightEditField = uieditfield(app.CurrentHeightControlLayout, 'numeric');
            app.CurrentHeightEditField.Limits = [0 Inf];
            app.CurrentHeightEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentHeightEditFieldValueChanged, true);
            app.CurrentHeightAddButton = uibutton(app.CurrentHeightControlLayout, 'state');
            app.CurrentHeightAddButton.ValueChangedFcn = createCallbackFcn(app, @CurrentHeightAddButtonValueChanged, true);
            app.CurrentHeightAddButton.Text = '+';
            app.CurrentHeightSubtractButton = uibutton(app.CurrentHeightControlLayout, 'state');
            app.CurrentHeightSubtractButton.ValueChangedFcn = createCallbackFcn(app, @CurrentHeightSubtractButtonValueChanged, true);
            app.CurrentHeightSubtractButton.Text = '-';
            app.SweepAutoFocusLayout = uigridlayout(app.AdjustFocusLayout);
            app.DarkfocusLabel = uilabel(app.SweepAutoFocusLayout);
            app.DarkfocusLabel.Text = 'Dark focus';
            app.StartmLabel = uilabel(app.SweepAutoFocusLayout);
            app.StartmLabel.Text = {'Start '; '[μm]'};
            app.SweepStartEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.SweepStepLabel = uilabel(app.SweepAutoFocusLayout);
            app.SweepStepLabel.Text = {'Range'; '[μm]'};
            app.SweepRangeEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.SweepStopLabel = uilabel(app.SweepAutoFocusLayout);
            app.SweepStopLabel.Text = {'Step '; '[μm]'};
            app.SweepStepEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.AutofocusButton = uibutton(app.SweepAutoFocusLayout, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Auto focus';
            app.AdjustFocusLabel = uilabel(app.AdjustFocusLayout);
            app.AdjustFocusLabel.Text = 'Adjust Focus';
            app.InspectROICheckboxLayout = uigridlayout(app.AdjustFocusLayout);
            app.InspectROICheckBox = uicheckbox(app.InspectROICheckboxLayout);
            app.InspectROICheckBox.ValueChangedFcn = createCallbackFcn(app, @InspectROICheckBoxValueChanged, true);
            app.InspectROICheckBox.Text = 'Inspect ROI';
            app.FocusDisplayLayout = uigridlayout(app.FocusLayout);
            app.FocusUIAxes = uiaxes(app.FocusDisplayLayout);
            app.FocusUIAxes.XTick = [];
            app.FocusUIAxes.YTick = [];
            app.FocusUIAxes.ZTick = [];
            app.FocusDisplayButtonsLayout = uigridlayout(app.FocusDisplayLayout);
            app.FocusPreviousButton = uibutton(app.FocusDisplayButtonsLayout, 'push');
            app.FocusPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.FocusPreviousButton.Text = '<';
            app.FocusNextButton = uibutton(app.FocusDisplayButtonsLayout, 'push');
            app.FocusNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.FocusNextButton.Text = '>';
            app.FocusAmplitudeButton = uibutton(app.FocusDisplayButtonsLayout, 'state');
            app.FocusAmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @FocusAmplitudeButtonValueChanged, true);
            app.FocusAmplitudeButton.Text = 'Amplitude';
            app.FocusAmplitudeButton.Value = true;
            app.FocusPhaseButton = uibutton(app.FocusDisplayButtonsLayout, 'state');
            app.FocusPhaseButton.ValueChangedFcn = createCallbackFcn(app, @FocusPhaseButtonValueChanged, true);
            app.FocusPhaseButton.Text = 'Phase';
            app.FocusGraphSettingsLayout = uigridlayout(app.FocusDisplayLayout);
            app.ValuerangeEditFieldLabel = uilabel(app.FocusGraphSettingsLayout);
            app.ValuerangeEditFieldLabel.Text = 'Value range:';
            app.FocusRangeStartEditField = uieditfield(app.FocusGraphSettingsLayout, 'numeric');
            app.FocusRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.FocusGraphSettingsLayout);
            app.EditFieldLabel.Text = '-';
            app.FocusRangeEndEditField = uieditfield(app.FocusGraphSettingsLayout, 'numeric');
            app.FocusRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.FocusRangeEndEditField.Value = 255;
            app.ShiftTab = uitab(app.NavigationTab);
            app.ShiftTab.Title = 'Shift';
            app.ShiftLayout = uigridlayout(app.ShiftTab);
            app.ShiftDisplayLayout = uigridlayout(app.ShiftLayout);
            app.ShiftUIAxes = uiaxes(app.ShiftDisplayLayout);
            app.ShiftUIAxes.XTick = [];
            app.ShiftUIAxes.YTick = [];
            app.ShiftUIAxes.ZTick = [];
            app.ShiftDisplayButtonsLayout = uigridlayout(app.ShiftDisplayLayout);
            app.ShiftPreviousButton = uibutton(app.ShiftDisplayButtonsLayout, 'push');
            app.ShiftPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.ShiftPreviousButton.Text = '<';
            app.ShiftNextButton = uibutton(app.ShiftDisplayButtonsLayout, 'push');
            app.ShiftNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.ShiftNextButton.Text = '>';
            app.ShiftAmplitudeButton = uibutton(app.ShiftDisplayButtonsLayout, 'state');
            app.ShiftAmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @ShiftAmplitudeButtonValueChanged, true);
            app.ShiftAmplitudeButton.Text = 'Amplitude';
            app.ShiftAmplitudeButton.Value = true;
            app.ShiftPhaseButton = uibutton(app.ShiftDisplayButtonsLayout, 'state');
            app.ShiftPhaseButton.ValueChangedFcn = createCallbackFcn(app, @ShiftPhaseButtonValueChanged, true);
            app.ShiftPhaseButton.Text = 'Phase';
            app.FocusGrahpSettingsLayout_2 = uigridlayout(app.ShiftDisplayLayout);
            app.ValuerangeEditFieldLabel_2 = uilabel(app.FocusGrahpSettingsLayout_2);
            app.ValuerangeEditFieldLabel_2.Text = 'Value range:';
            app.ShiftRangeStartEditField = uieditfield(app.FocusGrahpSettingsLayout_2, 'numeric');
            app.ShiftRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.EditFieldLabel_3 = uilabel(app.FocusGrahpSettingsLayout_2);
            app.EditFieldLabel_3.Text = '-';
            app.ShiftRangeEndEditField = uieditfield(app.FocusGrahpSettingsLayout_2, 'numeric');
            app.ShiftRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.ShiftRangeEndEditField.Value = 255;
            app.ShiftControlLayout = uigridlayout(app.ShiftLayout);
            app.ShiftFilesPanel = uipanel(app.ShiftControlLayout);
            app.ShiftFileListBoxLayout = uigridlayout(app.ShiftFilesPanel);
            app.FilesListBox_3Label_2 = uilabel(app.ShiftFileListBoxLayout);
            app.FilesListBox_3Label_2.Text = 'Holograms';
            app.ShiftFilesListBox = uilistbox(app.ShiftFileListBoxLayout);
            app.ShiftFilesListBox.Items = {''};
            app.ShiftFilesListBox.Value = {};
            app.GridLayout3_2 = uigridlayout(app.ShiftControlLayout);
            app.CorrectShiftLabel = uilabel(app.GridLayout3_2);
            app.CorrectShiftLabel.Text = 'Correct Shift';
            app.AutocorrectButton = uibutton(app.GridLayout3_2, 'push');
            app.AutocorrectButton.ButtonPushedFcn = createCallbackFcn(app, @AutocorrectButtonPushed, true);
            app.AutocorrectButton.Text = 'Auto correct';
            app.HeightCorrectionTable = uitable(app.GridLayout3_2);
            app.HeightCorrectionTable.ColumnName = {'Heights'; 'New Heights'};
            app.HeightCorrectionTable.RowName = {};
            app.ManualcorrectButton = uibutton(app.GridLayout3_2, 'push');
            app.ManualcorrectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualcorrectButtonPushed, true);
            app.ManualcorrectButton.Text = 'Manual correct';
            app.XYTable = uitable(app.GridLayout3_2);
            app.XYTable.ColumnName = {'X'; 'Y'};
            app.XYTable.RowName = {};
            app.ShiftHologramTypeDropDown = uidropdown(app.ShiftControlLayout);
            app.ShiftHologramTypeDropDown.Items = {'Reconstruction', 'Holograms', 'Shifted Reconstruction', 'Shifted Holograms'};
            app.ShiftHologramTypeDropDown.ItemsData = {'Reconstruction', 'Holograms', 'Shifted Reconstruction', 'Shifted Holograms'};
            app.ShiftHologramTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShiftHologramTypeDropDownValueChanged, true);
            app.ShiftHologramTypeDropDown.Value = 'Reconstruction';
            app.ReconstructionTab = uitab(app.NavigationTab);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.ReconstructionLayout = uigridlayout(app.ReconstructionTab);
            app.ReconstructionDisplayLayout = uigridlayout(app.ReconstructionLayout);
            app.ReconstructionUIAxes = uiaxes(app.ReconstructionDisplayLayout);
            app.ReconstructionUIAxes.XTick = [];
            app.ReconstructionUIAxes.YTick = [];
            app.ReconstructionUIAxes.ZTick = [];
            app.ReconstructionDisplayButtonsLayout = uigridlayout(app.ReconstructionDisplayLayout);
            app.ReconstructionPreviousButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'push');
            app.ReconstructionPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.ReconstructionPreviousButton.Text = '<';
            app.ReconstructionNextButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'push');
            app.ReconstructionNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.ReconstructionNextButton.Text = '>';
            app.ReconstructionAmplitudeButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'state');
            app.ReconstructionAmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionAmplitudeButtonValueChanged, true);
            app.ReconstructionAmplitudeButton.Text = 'Amplitude';
            app.ReconstructionAmplitudeButton.Value = true;
            app.ReconstructionPhaseButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'state');
            app.ReconstructionPhaseButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionPhaseButtonValueChanged, true);
            app.ReconstructionPhaseButton.Text = 'Phase';
            app.FocusGrahpSettingsLayout_3 = uigridlayout(app.ReconstructionDisplayLayout);
            app.ValuerangeEditFieldLabel_3 = uilabel(app.FocusGrahpSettingsLayout_3);
            app.ValuerangeEditFieldLabel_3.Text = 'Value range:';
            app.ReconstructionRangeStartEditField = uieditfield(app.FocusGrahpSettingsLayout_3, 'numeric');
            app.ReconstructionRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.EditFieldLabel_4 = uilabel(app.FocusGrahpSettingsLayout_3);
            app.EditFieldLabel_4.Text = '-';
            app.ReconstructionRangeEndEditField = uieditfield(app.FocusGrahpSettingsLayout_3, 'numeric');
            app.ReconstructionRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.ReconstructionRangeEndEditField.Value = 255;
            app.ReconstructionControlLayout = uigridlayout(app.ReconstructionLayout);
            app.ReconstructionHologramsPanel = uipanel(app.ReconstructionControlLayout);
            app.ReconstructionHologramsListBoxLayout = uigridlayout(app.ReconstructionHologramsPanel);
            app.FilesListBox_3Label_3 = uilabel(app.ReconstructionHologramsListBoxLayout);
            app.FilesListBox_3Label_3.Text = 'Holograms';
            app.ReconstructionHologramsListBox = uilistbox(app.ReconstructionHologramsListBoxLayout);
            app.ReconstructionHologramsListBox.Items = {''};
            app.ReconstructionHologramsListBox.Value = {};
            app.GridLayout3_3 = uigridlayout(app.ReconstructionControlLayout);
            app.ReconstructioncontrolLabel = uilabel(app.GridLayout3_3);
            app.ReconstructioncontrolLabel.Text = 'Reconstruction control';
            app.StartReconstruction = uibutton(app.GridLayout3_3, 'push');
            app.StartReconstruction.ButtonPushedFcn = createCallbackFcn(app, @StartReconstructionButtonPushed, true);
            app.StartReconstruction.Text = 'Start';
            app.ReconstructionParametersLayout = uigridlayout(app.GridLayout3_3);
            app.Label_2 = uilabel(app.ReconstructionParametersLayout);
            app.Label_2.Text = 'σ - parameter';
            app.SigmaEditField = uieditfield(app.ReconstructionParametersLayout, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.IterationNumberEditFieldLabel = uilabel(app.ReconstructionParametersLayout);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.ReconstructionParametersLayout, 'numeric');
            app.IterationNumberEditField.Limits = [1 Inf];
            app.IterationNumberEditField.RoundFractionalValues = 'on';
            app.IterationNumberEditField.ValueDisplayFormat = '%.0f';
            app.IterationNumberEditField.Value = 1;
            app.ResultsTab = uitab(app.NavigationTab);
            app.ResultsTab.Title = 'Results';
            app.ResultsLayout = uigridlayout(app.ResultsTab);
            app.SaveSettingsLayout = uigridlayout(app.ResultsLayout);
            app.ExportSettingsLayout = uigridlayout(app.SaveSettingsLayout);
            app.ExportsettingsLabel = uilabel(app.ExportSettingsLayout);
            app.ExportsettingsLabel.Text = 'Export settings';
            app.ExporthologramsButton = uibutton(app.ExportSettingsLayout, 'push');
            app.ExporthologramsButton.ButtonPushedFcn = createCallbackFcn(app, @ExporthologramsButtonPushed, true);
            app.ExporthologramsButton.Text = 'Export holograms';
            app.DropDownLayout_2 = uigridlayout(app.ExportSettingsLayout);
            app.ExportModeDropDownLabel = uilabel(app.DropDownLayout_2);
            app.ExportModeDropDownLabel.Text = 'Export Mode';
            app.ExportModeDropDown = uidropdown(app.DropDownLayout_2);
            app.ExportModeDropDown.Items = {'Amplitude', 'Phase', 'Both'};
            app.ExportModeDropDown.Value = 'Both';
            app.DropDownLayout = uigridlayout(app.ExportSettingsLayout);
            app.FormatDropDownLabel = uilabel(app.DropDownLayout);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.DropDownLayout);
            app.FormatDropDown.Items = {'.png', '.mat'};
            app.FormatDropDown.Value = '.png';
            app.SaveSelectorLayout = uigridlayout(app.ResultsLayout);
            app.AvailableHologramsListBoxLabel = uilabel(app.SaveSelectorLayout);
            app.AvailableHologramsListBoxLabel.Text = 'Available Holograms';
            app.AvailableHologramsListBox = uilistbox(app.SaveSelectorLayout);
            app.AvailableHologramsListBox.Items = {};
            app.AvailableHologramsListBox.Value = {};
            app.ExportedHologramsListBoxLabel = uilabel(app.SaveSelectorLayout);
            app.ExportedHologramsListBoxLabel.Text = 'Exported Holograms';
            app.ExportedHologramsListBox = uilistbox(app.SaveSelectorLayout);
            app.ExportedHologramsListBox.Items = {};
            app.ExportedHologramsListBox.Value = {};
            app.GridLayout7 = uigridlayout(app.SaveSelectorLayout);
            app.MoveAllRight = uibutton(app.GridLayout7, 'push');
            app.MoveAllRight.ButtonPushedFcn = createCallbackFcn(app, @MoveAllRightButtonPushed, true);
            app.MoveAllRight.Text = '>>';
            app.MoveSingleRight = uibutton(app.GridLayout7, 'push');
            app.MoveSingleRight.ButtonPushedFcn = createCallbackFcn(app, @MoveSingleRightButtonPushed, true);
            app.MoveSingleRight.Text = '>';
            app.MoveSingleLeft = uibutton(app.GridLayout7, 'push');
            app.MoveSingleLeft.ButtonPushedFcn = createCallbackFcn(app, @MoveSingleLeftButtonPushed, true);
            app.MoveSingleLeft.Text = '<';
            app.MoveAllLeft = uibutton(app.GridLayout7, 'push');
            app.MoveAllLeft.ButtonPushedFcn = createCallbackFcn(app, @MoveAllLeftButtonPushed, true);
            app.MoveAllLeft.Text = '<<';
            app.LogoNavLayout = uigridlayout(app.SideLayout);
            app.Image = uiimage(app.LogoNavLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Najlepsze_logo.png');
            app.NavigationLayout = uigridlayout(app.LogoNavLayout);
            app.FocusButton = uibutton(app.NavigationLayout, 'state');
            app.FocusButton.ValueChangedFcn = createCallbackFcn(app, @FocusButtonValueChanged, true);
            app.FocusButton.Text = 'Focus';
            app.ReconstructionButton = uibutton(app.NavigationLayout, 'state');
            app.ReconstructionButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionButtonValueChanged, true);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.ExportResultsButton = uibutton(app.NavigationLayout, 'state');
            app.ExportResultsButton.ValueChangedFcn = createCallbackFcn(app, @ExportResultsButtonValueChanged, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.LoadDataButton = uibutton(app.NavigationLayout, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadDataButton.Value = true;
            app.ShiftButton = uibutton(app.NavigationLayout, 'state');
            app.ShiftButton.ValueChangedFcn = createCallbackFcn(app, @ShiftButtonValueChanged, true);
            app.ShiftButton.Text = 'Shift';
            app.GPUProcessingCheckBox = uicheckbox(app.NavigationLayout);
            app.GPUProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPUProcessingCheckBoxValueChanged, true);
            app.GPUProcessingCheckBox.Text = 'GPU Processing';
            app.ModePanel = uipanel(app.UIFigure);
            app.ModePanel.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end