classdef HoloPrep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HologramPreparationwindowUIFigure  matlab.ui.Figure
        HelperPanel                  matlab.ui.container.Panel
        MainLayout                   matlab.ui.container.GridLayout
        LoadHologramsPanelLayout     matlab.ui.container.GridLayout
        ListBoxLayout                matlab.ui.container.GridLayout
        DataSourceListBox            matlab.ui.control.ListBox
        HologramsLabel               matlab.ui.control.Label
        PreviewLayout                matlab.ui.container.GridLayout
        LoadHologramsDisplayButtonsLayout  matlab.ui.container.GridLayout
        LoadHologramsNextButton      matlab.ui.control.Button
        LoadHologramsPreviousButton  matlab.ui.control.Button
        LoadHologramsUIAxes          matlab.ui.control.UIAxes
        ConfigureLayout              matlab.ui.container.GridLayout
        LoadedLayersLayout           matlab.ui.container.GridLayout
        LoadedLayersDropDown         matlab.ui.control.DropDown
        LoadedLayersDropDownLabel    matlab.ui.control.Label
        CropAndPadWithPaddingLayout  matlab.ui.container.GridLayout
        CropLayersLabel              matlab.ui.control.Label
        CropLayersNumberLayout       matlab.ui.container.GridLayout
        CropLayerNumberEditField     matlab.ui.control.NumericEditField
        LayerNumberEditFieldLabel    matlab.ui.control.Label
        CropLayersButtonsLayout      matlab.ui.container.GridLayout
        ResetCropButton              matlab.ui.control.Button
        CropLeftButton               matlab.ui.control.StateButton
        CropBottomButton             matlab.ui.control.StateButton
        CropRightButton              matlab.ui.control.StateButton
        CropTopButton                matlab.ui.control.StateButton
        PadLayersLayout              matlab.ui.container.GridLayout
        PadLayerNumberLayout         matlab.ui.container.GridLayout
        PadLayerNumberEditField      matlab.ui.control.NumericEditField
        LayerNumberLabel             matlab.ui.control.Label
        PadLayersLabel               matlab.ui.control.Label
        AverageSettingsLayout        matlab.ui.container.GridLayout
        HighpassfilterLabel          matlab.ui.control.Label
        SigmaParameterLayout         matlab.ui.container.GridLayout
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        AveragingCheckBox            matlab.ui.control.CheckBox
        ConfigureloadLabel           matlab.ui.control.Label
        AddDeleteControls            matlab.ui.container.GridLayout
        AddButton                    matlab.ui.control.Button
        AddAllButton                 matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        DeleteAllButton              matlab.ui.control.Button
        LoadedHologramsLayout        matlab.ui.container.GridLayout
        ListBoxLayout2               matlab.ui.container.GridLayout
        LoadedHologamsListBox        matlab.ui.control.ListBox
        HologramsLabel_2             matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Files
        SelectedHologram  (:,:,:)                               = []
        LoadedHolograms   (:,:,:)                               = []
        hologramLayerIndex                                      = 1
        
        callerApp
    end
    
    methods (Access = private)
        
        function LoadFilesToDataSourceListBox(app)
            filesNumber = length(app.Files);
            filesNames = cell(1, filesNumber);

            for i = 1:filesNumber
                filesNames{i} = app.Files(i).name;
            end

            app.DataSourceListBox.Items = filesNames;
            app.DataSourceListBox.ItemsData = 1:filesNumber;
        end

        function hologram = LoadHologramFromFiles(app, files, index)
            fileName = files(index).name;
            filePath = fullfile(files(index).folder, fileName);
         
            [~, ~, ext] = fileparts(fileName);
         
            switch lower(ext)
                case '.mat'
                    hologram = cell2mat(struct2cell(load(filePath)));
                case {'.png', '.tif', '.tiff', '.bmp'}
                    hologram = imread(filePath);
                otherwise
                    error('HoloPrep:UnsupportedFormat', ...
                          'Unsupported file format: %s', ext);
            end
         
            hologram = double(hologram);
         
            if size(hologram, 3) == 1
                app.LoadHologramsNextButton.Enable = "off";
                app.LoadHologramsPreviousButton.Enable = "off";
            else
                app.LoadHologramsNextButton.Enable = "on";
                app.LoadHologramsPreviousButton.Enable = "on";
            end
        end

        function hologram = ProcessHologram(app, hologram)
            hologram = app.AverageHologram(hologram);

            croppedLayerCount = app.CropLayerNumberEditField.Value;
            if app.validateCroppedLayerNumber(hologram, croppedLayerCount)
                layerCount = size(hologram, 3);
                cropped = app.CropHologram(hologram(:,:,1), croppedLayerCount);
                cropped(:,:,2:layerCount) = 0;
                for k = 2:layerCount
                    cropped(:,:,k) = app.CropHologram(hologram(:,:,k), croppedLayerCount);
                end
                hologram = cropped;
            end

            paddingValue = app.PadLayerNumberEditField.Value;
            if paddingValue > 0
                hologram = app.PadHologram(hologram, paddingValue);
            end
        end

        function HandleHologramChange(app, index)
            hologram = app.LoadHologramFromFiles(app.Files, index);
         
            % Reset so the layer index never points past the new file's layers
            app.hologramLayerIndex = 1;
         
            if size(hologram, 3) == 1
                app.LoadedLayersDropDown.Items = {'1'};
                app.LoadedLayersDropDown.ItemsData = {'1'};
                app.LoadedLayersDropDown.Value = '1';
                app.LoadedLayersDropDown.Enable = "off";
            elseif size(hologram, 3) == 3
                app.LoadedLayersDropDown.Items = {'1', '2', '3', '[1,2]', '[2,3]', '[1,2,3]'};
                app.LoadedLayersDropDown.ItemsData = {'1', '2', '3', '1:2', '2:3', '1:3'};
                app.LoadedLayersDropDown.Value = '1';   % must be reset after Items change
                app.LoadedLayersDropDown.Enable = "on";
            else
                app.LoadedLayersDropDown.Items = {'1', 'all'};
                app.LoadedLayersDropDown.ItemsData = {'1', ':'};
                app.LoadedLayersDropDown.Value = ':';
                app.LoadedLayersDropDown.Enable = "on";
            end
         
            % Validate/crop/pad against the NEW hologram (old code validated
            % against the previous app.SelectedHologram)
            app.SelectedHologram = app.ProcessHologram(hologram);
        end

        function DisplaySelectedHologram(app, hologramLayerIndex)
            index = app.DataSourceListBox.Value;
         
            layerCount = size(app.SelectedHologram, 3);
            hologramLayerIndex = min(max(hologramLayerIndex, 1), layerCount);
         
            hologram = app.SelectedHologram(:,:,hologramLayerIndex);  % already double
         
            if layerCount == 1
                hologramTitle = app.Files(index).name + " 1/1";
            else
                hologramTitle = app.Files(index).name + " " + ...
                    num2str(hologramLayerIndex) + "/" + num2str(layerCount);
            end
            title(app.LoadHologramsUIAxes, "\color{white}" + hologramTitle);
         
            % Data is already normalized (mean ~ 1), so display [0, max] as-is.
            % The old [0 max/mean2] normalized a second time.
            hi = max(hologram(:));
            if hi > 0
                range = [0 hi];
            else
                range = [];   % degenerate/constant image: let imshow autoscale
            end
         
            app.LoadHologramsUIAxes.XLim(2) = size(hologram, 2);
            app.LoadHologramsUIAxes.YLim(2) = size(hologram, 1);
         
            imshow(hologram, range, "Parent", app.LoadHologramsUIAxes);
        end

        function padedHologram = PadHologram(app, hologram, paddingValue)
            padedHologram = padarray(hologram,[paddingValue, paddingValue], "replicate", "both");
        end

        function isValid = validateCroppedLayerNumber(app, hologram, croppedLayerNumber) 
            height = size(hologram, 1);
            width  = size(hologram, 2);
         
            cropTop    = app.CropTopButton.Value    * croppedLayerNumber;
            cropBottom = app.CropBottomButton.Value * croppedLayerNumber;
            cropLeft   = app.CropLeftButton.Value   * croppedLayerNumber;
            cropRight  = app.CropRightButton.Value  * croppedLayerNumber;
         
            if (cropTop + cropBottom) >= height
                warning('The selected cropping value (%d) for top and bottom exceeds the image height (%d).', ...
                        croppedLayerNumber, height);
                isValid = false;
                return;
            end
         
            if (cropLeft + cropRight) >= width
                warning('The selected cropping value (%d) for left and right exceeds the image width (%d).', ...
                        croppedLayerNumber, width);
                isValid = false;
                return;
            end
         
            isValid = true;
        end

        function croppedHologram = CropHologram(app, hologram, croppedLayerNumber)
            % Get the size of the 2D hologram
            [height, width] = size(hologram);
        
            % Default cropping values
            cropTop =0;
            cropBottom = 0;
            cropLeft = 0;
            cropRight = 0;
        
            % If the button is pressed (value == 0), apply the crop
            if app.CropTopButton.Value
                cropTop = croppedLayerNumber;
            end
            if app.CropBottomButton.Value
                cropBottom = croppedLayerNumber;
            end
            if app.CropLeftButton.Value
                cropLeft = croppedLayerNumber;
            end
            if app.CropRightButton.Value
                cropRight = croppedLayerNumber;
            end
        
            % Compute bounds
            top = 1 + cropTop;
            bottom = height - cropBottom;
            left = 1 + cropLeft;
            right = width - cropRight;
        
            % Ensure bounds are valid
            top = max(1, top);
            bottom = min(height, bottom);
            left = max(1, left);
            right = min(width, right);
        
            % Apply cropping
            croppedHologram = hologram(top:bottom, left:right);
        end

        function averagedHologram = AverageHologram(app, hologram)
            averagingOn = app.AveragingCheckBox.Value;
            sigma = app.SigmaEditField.Value;
         
            averagedHologram = zeros(size(hologram));
         
            % Per-layer processing: mean2 over a 3D stack would mix layers
            % with different exposure into one global mean.
            for k = 1:size(hologram, 3)
                layer = hologram(:,:,k);
                if averagingOn && sigma < inf
                    background = imgaussfilt(layer, sigma);
                    % Guard against division blow-up in near-zero regions
                    floorValue = 1e-3 * mean2(abs(background));
                    background(background < floorValue) = floorValue;
                    averagedHologram(:,:,k) = layer ./ background;
                else
                    averagedHologram(:,:,k) = layer / mean2(layer);
                end
            end
        end

        function AddHologram(app, index)
            hologram = app.LoadHologramFromFiles(app.Files, index);
         
            % Same pipeline as the preview: average -> crop -> pad
            hologram = app.ProcessHologram(hologram);
         
            totalLayers = size(hologram, 3);
         
            if totalLayers == 1
                layers = 1;
            else
                layerString = app.LoadedLayersDropDown.Value;
                layers = app.LayerStringToIndexArray(layerString, totalLayers);
                layers = layers(layers >= 1 & layers <= totalLayers);  % safety clamp
            end
            layerCount = numel(layers);
         
            selected = hologram(:,:,layers);
         
            if isempty(app.LoadedHolograms)
                % Old code indexed (:,:,end) on an empty array -> invalid index 0
                app.LoadedHolograms = selected;
            else
                if size(selected,1) ~= size(app.LoadedHolograms,1) || ...
                   size(selected,2) ~= size(app.LoadedHolograms,2)
                    warning('Hologram "%s" has a different size (%dx%d) than the loaded stack (%dx%d) — skipped. Adjust crop/pad to match.', ...
                        app.Files(index).name, size(selected,1), size(selected,2), ...
                        size(app.LoadedHolograms,1), size(app.LoadedHolograms,2));
                    return;
                end
                app.LoadedHolograms(:,:,end+1:end+layerCount) = selected;
            end
         
            name = strsplit(app.Files(index).name, ".");
            for layerArrayIndex = 1:layerCount
                if totalLayers == 1
                    itemName = name{1};
                else
                    itemName = [name{1} num2str(layers(layerArrayIndex))];
                end
                app.LoadedHologamsListBox.Items{end + 1} = itemName;
                app.LoadedHologamsListBox.ItemsData(end + 1) = ...
                    length(app.LoadedHologamsListBox.ItemsData) + 1;
            end
        end

        function indexArray = LayerStringToIndexArray(app, layerString, layerCount)
            if contains(layerString, ":")
                if isscalar(layerString)
                    indexArray = 1:layerCount;
                else
                    layers = strsplit(layerString, ":");
                    indexArray = str2double(layers(1)):str2double(layers(2));
                end
            else
                indexArray = str2double(layerString);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            app.callerApp = callerApp;
            app.Files = app.callerApp.Files;
         
            app.LoadFilesToDataSourceListBox();
         
            app.DataSourceListBox.Value = 1;
         
            % Route through the same path as every later file change, so the
            % first hologram is averaged/cropped/padded like all the others.
            app.HandleHologramChange(1);
         
            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: LoadHologramsPreviousButton
        function LoadHologramsPreviousButtonPushed(app, event)
            app.hologramLayerIndex = app.hologramLayerIndex - 1;

            if(app.hologramLayerIndex<1)
                app.hologramLayerIndex = size(app.SelectedHologram,3);
            end

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: LoadHologramsNextButton
        function LoadHologramsNextButtonPushed(app, event)
            app.hologramLayerIndex = app.hologramLayerIndex + 1;
            if(app.hologramLayerIndex>size(app.SelectedHologram,3))
                app.hologramLayerIndex = 1;
            end
            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Value changed function: AveragingCheckBox, DataSourceListBox, 
        % ...and 2 other components
        function HologramChanged(app, event)
            index = app.DataSourceListBox.Value;
            
            app.HandleHologramChange(index);

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: AddButton
        function AddButtonButtonPushed(app, event)
            index = app.DataSourceListBox.Value;
            
            app.AddHologram(index);
        end

        % Button pushed function: AddAllButton
        function AddAllButtonPushed(app, event)
            app.LoadedHolograms = [];
            app.LoadedHologamsListBox.Items = {};
            app.LoadedHologamsListBox.ItemsData = [];

            for index = 1:length(app.Files)
                app.AddHologram(index);
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.LoadedHolograms = [];
            app.LoadedHologamsListBox.Items = {};
            app.LoadedHologamsListBox.ItemsData = [];
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            index = app.LoadedHologamsListBox.Value;

            app.LoadedHolograms(:,:, index) = [];
            app.LoadedHologamsListBox.Items(index) = [];
            app.LoadedHologamsListBox.ItemsData(index:end) = app.LoadedHologamsListBox.ItemsData(index:end) - 1;
            app.LoadedHologamsListBox.ItemsData(index) = [];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.callerApp.HologramsRaw = app.LoadedHolograms;
            app.callerApp.HologramsRawNames = app.LoadedHologamsListBox.Items;
            delete(app);
        end

        % Close request function: HologramPreparationwindowUIFigure
        function HologramPreparationwindowUIFigureCloseRequest(app, event)
            app.callerApp.PopupStatus = -1;
            delete(app)
        end

        % Value changed function: CropBottomButton, 
        % ...and 4 other components
        function CropValueChanged(app, event)
            index = app.DataSourceListBox.Value;
            
            app.HandleHologramChange(index);

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: ResetCropButton
        function ResetCropButtonPushed(app, event)
            app.CropLeftButton.Value = 0;
            app.CropBottomButton.Value = 0;
            app.CropRightButton.Value = 0;
            app.CropTopButton.Value = 0;
            
            index = app.DataSourceListBox.Value;
            
            app.HandleHologramChange(index);

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HologramPreparationwindowUIFigure = uifigure('Visible', 'off');
            app.HologramPreparationwindowUIFigure.Name = 'Hologram Preparation window';
            app.HologramPreparationwindowUIFigure.Icon = 'AppLogo_noBackground.png';
            app.HologramPreparationwindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @HologramPreparationwindowUIFigureCloseRequest, true);
            app.MainLayout = uigridlayout(app.HologramPreparationwindowUIFigure);
            app.LoadHologramsPanelLayout = uigridlayout(app.MainLayout);
            app.LoadedHologramsLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.LoadButton = uibutton(app.LoadedHologramsLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ListBoxLayout2 = uigridlayout(app.LoadedHologramsLayout);
            app.HologramsLabel_2 = uilabel(app.ListBoxLayout2);
            app.HologramsLabel_2.Text = 'Loaded Holograms';
            app.LoadedHologamsListBox = uilistbox(app.ListBoxLayout2);
            app.LoadedHologamsListBox.Items = {};
            app.LoadedHologamsListBox.Value = {};
            app.AddDeleteControls = uigridlayout(app.LoadHologramsPanelLayout);
            app.DeleteAllButton = uibutton(app.AddDeleteControls, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = {'XX'; 'Delete '; 'all'};
            app.DeleteButton = uibutton(app.AddDeleteControls, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = {'X'; 'Delete'};
            app.AddAllButton = uibutton(app.AddDeleteControls, 'push');
            app.AddAllButton.ButtonPushedFcn = createCallbackFcn(app, @AddAllButtonPushed, true);
            app.AddAllButton.Text = {'++'; 'Add '; 'all'};
            app.AddButton = uibutton(app.AddDeleteControls, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonButtonPushed, true);
            app.AddButton.Text = {'+'; 'Add'};
            app.ConfigureLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.ConfigureloadLabel = uilabel(app.ConfigureLayout);
            app.ConfigureloadLabel.Text = 'Configure load';
            app.AverageSettingsLayout = uigridlayout(app.ConfigureLayout);
            app.AveragingCheckBox = uicheckbox(app.AverageSettingsLayout);
            app.AveragingCheckBox.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.AveragingCheckBox.Text = 'Filter on/off';
            app.SigmaParameterLayout = uigridlayout(app.AverageSettingsLayout);
            app.SigmaEditFieldLabel = uilabel(app.SigmaParameterLayout);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.SigmaParameterLayout, 'numeric');
            app.SigmaEditField.Limits = [0.1 Inf];
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.SigmaEditField.Value = 10;
            app.HighpassfilterLabel = uilabel(app.AverageSettingsLayout);
            app.HighpassfilterLabel.Text = 'High-pass filter';
            app.PadLayersLayout = uigridlayout(app.ConfigureLayout);
            app.PadLayersLabel = uilabel(app.PadLayersLayout);
            app.PadLayersLabel.Text = 'Pad Layers';
            app.PadLayerNumberLayout = uigridlayout(app.PadLayersLayout);
            app.LayerNumberLabel = uilabel(app.PadLayerNumberLayout);
            app.LayerNumberLabel.Text = ' Layer Number';
            app.PadLayerNumberEditField = uieditfield(app.PadLayerNumberLayout, 'numeric');
            app.PadLayerNumberEditField.Limits = [0 Inf];
            app.PadLayerNumberEditField.RoundFractionalValues = 'on';
            app.PadLayerNumberEditField.ValueDisplayFormat = '%.0f';
            app.PadLayerNumberEditField.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.CropAndPadWithPaddingLayout = uigridlayout(app.ConfigureLayout);
            app.CropLayersButtonsLayout = uigridlayout(app.CropAndPadWithPaddingLayout);
            app.CropTopButton = uibutton(app.CropLayersButtonsLayout, 'state');
            app.CropTopButton.ValueChangedFcn = createCallbackFcn(app, @CropValueChanged, true);
            app.CropTopButton.Icon = 'PadTop.svg';
            app.CropTopButton.Text = '';
            app.CropRightButton = uibutton(app.CropLayersButtonsLayout, 'state');
            app.CropRightButton.ValueChangedFcn = createCallbackFcn(app, @CropValueChanged, true);
            app.CropRightButton.Icon = 'PadRight.svg';
            app.CropRightButton.Text = '';
            app.CropBottomButton = uibutton(app.CropLayersButtonsLayout, 'state');
            app.CropBottomButton.ValueChangedFcn = createCallbackFcn(app, @CropValueChanged, true);
            app.CropBottomButton.Icon = 'PadBottom.svg';
            app.CropBottomButton.Text = '';
            app.CropLeftButton = uibutton(app.CropLayersButtonsLayout, 'state');
            app.CropLeftButton.ValueChangedFcn = createCallbackFcn(app, @CropValueChanged, true);
            app.CropLeftButton.Icon = 'PadLeft.svg';
            app.CropLeftButton.Text = '';
            app.ResetCropButton = uibutton(app.CropLayersButtonsLayout, 'push');
            app.ResetCropButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCropButtonPushed, true);
            app.ResetCropButton.Icon = 'PadNothing.svg';
            app.ResetCropButton.Text = '';
            app.CropLayersNumberLayout = uigridlayout(app.CropAndPadWithPaddingLayout);
            app.LayerNumberEditFieldLabel = uilabel(app.CropLayersNumberLayout);
            app.LayerNumberEditFieldLabel.Text = ' Layer Number';
            app.CropLayerNumberEditField = uieditfield(app.CropLayersNumberLayout, 'numeric');
            app.CropLayerNumberEditField.ValueChangedFcn = createCallbackFcn(app, @CropValueChanged, true);
            app.CropLayersLabel = uilabel(app.CropAndPadWithPaddingLayout);
            app.CropLayersLabel.Text = 'Crop Layers';
            app.LoadedLayersLayout = uigridlayout(app.ConfigureLayout);
            app.LoadedLayersDropDownLabel = uilabel(app.LoadedLayersLayout);
            app.LoadedLayersDropDownLabel.Text = 'Loaded Layers';
            app.LoadedLayersDropDown = uidropdown(app.LoadedLayersLayout);
            app.LoadedLayersDropDown.Items = {'1', '2', '3', '[1,2]', '[2,3]', '[1,2,3]'};
            app.LoadedLayersDropDown.ItemsData = {'1', '2', '3', '1:2', '2:3', '1:3'};
            app.LoadedLayersDropDown.Value = '1';
            app.PreviewLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.LoadHologramsUIAxes = uiaxes(app.PreviewLayout);
            app.LoadHologramsUIAxes.XTick = [];
            app.LoadHologramsUIAxes.YTick = [];
            app.LoadHologramsUIAxes.ZTick = [];
            app.LoadHologramsDisplayButtonsLayout = uigridlayout(app.PreviewLayout);
            app.LoadHologramsPreviousButton = uibutton(app.LoadHologramsDisplayButtonsLayout, 'push');
            app.LoadHologramsPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsPreviousButtonPushed, true);
            app.LoadHologramsPreviousButton.Text = '<';
            app.LoadHologramsNextButton = uibutton(app.LoadHologramsDisplayButtonsLayout, 'push');
            app.LoadHologramsNextButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsNextButtonPushed, true);
            app.LoadHologramsNextButton.Text = '>';
            app.ListBoxLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.HologramsLabel = uilabel(app.ListBoxLayout);
            app.HologramsLabel.Text = 'Data source';
            app.DataSourceListBox = uilistbox(app.ListBoxLayout);
            app.DataSourceListBox.Items = {};
            app.DataSourceListBox.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.DataSourceListBox.Value = {};
            app.HelperPanel = uipanel(app.HologramPreparationwindowUIFigure);
            % Show the figure after all components are created
            app.HologramPreparationwindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloPrep(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HologramPreparationwindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HologramPreparationwindowUIFigure)
        end
    end
end